classdef CurrentGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        WelcomeLabel                    matlab.ui.control.Label
        HomesteadFLButton_2             matlab.ui.control.Button
        ChelanCountyWAButton_2          matlab.ui.control.Button
        GreatPlainsWestMTButton_2       matlab.ui.control.Button
        DemoModeLabel_2                 matlab.ui.control.Label
        InputDataTab                    matlab.ui.container.Tab
        LoadSolarDataButton             matlab.ui.control.Button
        LoadDemandDataButton            matlab.ui.control.Button
        LoadDataLabel                   matlab.ui.control.Label
        TextArea_2                      matlab.ui.control.TextArea
        SolarArrayScaleFactorEditFieldLabel  matlab.ui.control.Label
        SolarArrayScaleFactorEditField  matlab.ui.control.NumericEditField
        SolarGenerationCheckBox         matlab.ui.control.CheckBox
        ElectricityDemandCheckBox       matlab.ui.control.CheckBox
        DatatoGraphLabel                matlab.ui.control.Label
        StartDatetographSliderLabel     matlab.ui.control.Label
        StartDatetographSlider          matlab.ui.control.Slider
        EndDatetographSliderLabel       matlab.ui.control.Label
        EndDatetographSlider            matlab.ui.control.Slider
        MaximumSliderLabel              matlab.ui.control.Label
        MaximumSlider                   matlab.ui.control.Slider
        MinimumSliderLabel              matlab.ui.control.Label
        MinimumSlider                   matlab.ui.control.Slider
        SizeofSolarArrayUploadedMWEditFieldLabel  matlab.ui.control.Label
        SizeofSolarArrayUploadedMWEditField  matlab.ui.control.NumericEditField
        DemandScaleFactorEditFieldLabel  matlab.ui.control.Label
        DemandScaleFactorEditField      matlab.ui.control.NumericEditField
        SubstationCapacityMWEditFieldLabel  matlab.ui.control.Label
        SubstationCapacityMWEditField   matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
        SystemSpecificationsTab         matlab.ui.container.Tab
        ESSAlgorithmButtonGroup         matlab.ui.container.ButtonGroup
        TimeBasedButton                 matlab.ui.control.ToggleButton
        LoadBasedButton                 matlab.ui.control.ToggleButton
        BatteryReplacementButtonGroup   matlab.ui.container.ButtonGroup
        YearsButton                     matlab.ui.control.ToggleButton
        CyclesButton                    matlab.ui.control.ToggleButton
        MinimumSOHButton                matlab.ui.control.ToggleButton
        ThroughputMWhButton             matlab.ui.control.ToggleButton
        ESSDepthofDischargeLabel        matlab.ui.control.Label
        ESSDepthofDischargeEditField    matlab.ui.control.NumericEditField
        ESSDurationhoursEditFieldLabel  matlab.ui.control.Label
        ESSDurationhoursEditField       matlab.ui.control.NumericEditField
        ESSInitialEnergyofMaximumEditFieldLabel  matlab.ui.control.Label
        ESSInitialEnergyofMaximumEditField  matlab.ui.control.NumericEditField
        PeakHoursEditFieldLabel         matlab.ui.control.Label
        PeakHoursEditField              matlab.ui.control.NumericEditField
        OffPeakHoursEditFieldLabel      matlab.ui.control.Label
        OffPeakHoursEditField           matlab.ui.control.NumericEditField
        BatteryReplacementEditFieldLabel  matlab.ui.control.Label
        BatteryReplacementEditField     matlab.ui.control.NumericEditField
        YearlyESSCapacityLossEditFieldLabel  matlab.ui.control.Label
        YearlyESSCapacityLossEditField  matlab.ui.control.NumericEditField
        YearlySolarCapacityLossEditFieldLabel  matlab.ui.control.Label
        YearlySolarCapacityLossEditField  matlab.ui.control.NumericEditField
        MonthlyESSchargeLossEditFieldLabel  matlab.ui.control.Label
        MonthlyESSchargeLossEditField   matlab.ui.control.NumericEditField
        InverterEfficiencyLabel         matlab.ui.control.Label
        InverterEfficiencyEditField     matlab.ui.control.NumericEditField
        DCDCConverterEfficiencyEditFieldLabel  matlab.ui.control.Label
        DCDCConverterEfficiencyEditField  matlab.ui.control.NumericEditField
        ESSRoundTripEfficiencyLabel     matlab.ui.control.Label
        ESSRoundTripEfficiencyEditField  matlab.ui.control.NumericEditField
        HardwareReplacementIntervalYearsEditFieldLabel  matlab.ui.control.Label
        HardwareReplacementIntervalYearsEditField  matlab.ui.control.NumericEditField
        PeakHoursEndTimeEditField       matlab.ui.control.NumericEditField
        OffPeakHoursEndTimeEditField    matlab.ui.control.NumericEditField
        SystemSpecificationsLabel       matlab.ui.control.Label
        EmergencyPowerofMaximumEditFieldLabel  matlab.ui.control.Label
        EmergencyPowerofMaximumEditField  matlab.ui.control.NumericEditField
        ChargebySolarThresholdLabel     matlab.ui.control.Label
        ChargebySolarThresholdEditField  matlab.ui.control.NumericEditField
        ChargePercentageLabel           matlab.ui.control.Label
        ChargePercentageEditField       matlab.ui.control.NumericEditField
        DischargePercentageLabel        matlab.ui.control.Label
        DischargePercentageEditField    matlab.ui.control.NumericEditField
        DischargeFactorLabel            matlab.ui.control.Label
        DischargeFactorEditField        matlab.ui.control.NumericEditField
        ESSPowerCapacityMWEditFieldLabel  matlab.ui.control.Label
        ESSPowerCapacityMWEditField     matlab.ui.control.NumericEditField
        SubstationPlannedRatingMWEditFieldLabel  matlab.ui.control.Label
        SubstationPlannedRatingMWEditField  matlab.ui.control.NumericEditField
        CostVariablesTab                matlab.ui.container.Tab
        CarbonCostpertonCO2USDEditFieldLabel  matlab.ui.control.Label
        CarbonCostpertonCO2USDEditField  matlab.ui.control.NumericEditField
        SolarInstallationCostperMWUSDEditFieldLabel  matlab.ui.control.Label
        SolarInstallationCostperMWUSDEditField  matlab.ui.control.NumericEditField
        SolarFixedInstallationCostUSDEditFieldLabel  matlab.ui.control.Label
        SolarFixedInstallationCostUSDEditField  matlab.ui.control.NumericEditField
        ESSInstallationCostperMWhUSDEditFieldLabel  matlab.ui.control.Label
        ESSInstallationCostperMWhUSDEditField  matlab.ui.control.NumericEditField
        ESSFixedInstallationCostUSDEditFieldLabel  matlab.ui.control.Label
        ESSFixedInstallationCostUSDEditField  matlab.ui.control.NumericEditField
        SolarOperationsandMaintenanceCostperMWUSDEditFieldLabel  matlab.ui.control.Label
        SolarOperationsandMaintenanceCostperMWUSDEditField  matlab.ui.control.NumericEditField
        ESSOperationsandMaintenanceCostperMWhUSDEditFieldLabel  matlab.ui.control.Label
        ESSOperationsandMaintenanceCostperMWhUSDEditField  matlab.ui.control.NumericEditField
        ESSFixedOperationsandMaintenanceCostUSDEditFieldLabel  matlab.ui.control.Label
        ESSFixedOperationsandMaintenanceCostUSDEditField  matlab.ui.control.NumericEditField
        SubstationUpgradeCostperMWUSDEditFieldLabel  matlab.ui.control.Label
        SubstationUpgradeCostperMWUSDEditField  matlab.ui.control.NumericEditField
        SolarPercentYearlyCostReductionLabel  matlab.ui.control.Label
        SolarPercentYearlyCostReductionEditField  matlab.ui.control.NumericEditField
        ESSPercentYearlyCostReductionLabel  matlab.ui.control.Label
        ESSPercentYearlyCostReductionEditField  matlab.ui.control.NumericEditField
        SolarFixedOperationsandMaintenanceCostUSDEditField_2Label  matlab.ui.control.Label
        SolarFixedOperationsandMaintenanceCostUSDEditField_2  matlab.ui.control.NumericEditField
        ESSReplacementCostperMWhUSDEditFieldLabel  matlab.ui.control.Label
        ESSReplacementCostperMWhUSDEditField  matlab.ui.control.NumericEditField
        ESSFixedReplacementCostUSDLabel  matlab.ui.control.Label
        ESSFixedReplacementCostUSDEditField_2  matlab.ui.control.NumericEditField
        SolarInstallationCarbonCostperMWtonsCO2EditFieldLabel  matlab.ui.control.Label
        SolarInstallationCarbonCostperMWtonsCO2EditField  matlab.ui.control.NumericEditField
        StorageInstallationCarbonCostperMWtonsCO2EditFieldLabel  matlab.ui.control.Label
        StorageInstallationCarbonCostperMWtonsCO2EditField  matlab.ui.control.NumericEditField
        SubstationUpgradeCarbonCostperMWtonsCO2EditFieldLabel  matlab.ui.control.Label
        SubstationUpgradeCarbonCostperMWtonsCO2EditField  matlab.ui.control.NumericEditField
        SolarOperationsandMaintenanceCarbonCostperMWtonsCO2Label  matlab.ui.control.Label
        SolarOperationsandMaintenanceCarbonCostperMW  matlab.ui.control.NumericEditField
        StorageOperationsandMaintenanceCarbonCostperMWtonsCO2Label  matlab.ui.control.Label
        StorageOperationsandMaintenanceCarbonCostperMW  matlab.ui.control.NumericEditField
        SubstationOperationsandMaintenanceCarbonCostperMWtonsCO2Label  matlab.ui.control.Label
        SubstationOperationsandMaintenanceCarbonCostperMW  matlab.ui.control.NumericEditField
        HardwareReplacementCarbonCostperMWtonsCO2Label  matlab.ui.control.Label
        HardwareReplacementCarbonCostperMW  matlab.ui.control.NumericEditField
        CostVariablesLabel              matlab.ui.control.Label
        PeakHoursCosttoGenerateElectricityperMWUSDEditFieldLabel  matlab.ui.control.Label
        PeakHoursCosttoGenerateElectricityperMWUSDEditField  matlab.ui.control.NumericEditField
        OffPeakHoursCosttoGenerateElectricityperMWUSDEditFieldLabel  matlab.ui.control.Label
        OffPeakHoursCosttoGenerateElectricityperMWUSDEditField  matlab.ui.control.NumericEditField
        InterestRateEditFieldLabel      matlab.ui.control.Label
        InterestRateEditField           matlab.ui.control.NumericEditField
        CarbonCostsLabel                matlab.ui.control.Label
        MonetaryCostsLabel              matlab.ui.control.Label
        SubstationOperationsandMaintenanceCostperMWUSDEditFieldLabel  matlab.ui.control.Label
        SubstationOperationsandMaintenanceCostperMWUSDEditField  matlab.ui.control.NumericEditField
        OptimizationTab                 matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        OptimizationVariablesTab        matlab.ui.container.Tab
        ProjectLifetimeYearsEditFieldLabel  matlab.ui.control.Label
        ProjectLifetimeYearsEditField   matlab.ui.control.NumericEditField
        BlackoutatSubstationRatingCheckBox  matlab.ui.control.CheckBox
        CarbonEmissionsperMWhofEnergyProducedtonsCO2EditFieldLabel  matlab.ui.control.Label
        CarbonEmissionsperMWhofEnergyProducedtonsCO2EditField  matlab.ui.control.NumericEditField
        OverloadBlackoutCostperHourUSDEditFieldLabel  matlab.ui.control.Label
        OverloadBlackoutCostperHourUSDEditField  matlab.ui.control.NumericEditField
        FixedOverloadBlackoutCostUSDEditFieldLabel  matlab.ui.control.Label
        FixedOverloadBlackoutCostUSDEditField  matlab.ui.control.NumericEditField
        SolarCapacityRangeforOptimizationMWEditFieldLabel  matlab.ui.control.Label
        SolarCapacityRangeforOptimizationMWEditField  matlab.ui.control.NumericEditField
        StorageCapacityRangeforOptimizationMWhEditFieldLabel  matlab.ui.control.Label
        StorageCapacityRangeforOptimizationMWhEditField  matlab.ui.control.NumericEditField
        SubstationUpgradeRangerforOptimizationMWEditFieldLabel  matlab.ui.control.Label
        SubstationUpgradeRangerforOptimizationMWEditField  matlab.ui.control.NumericEditField
        TextArea_3                      matlab.ui.control.TextArea
        OptimizationVariablesLabel      matlab.ui.control.Label
        PredictedYearlyLoadGrowthEditField_2Label  matlab.ui.control.Label
        PredictedYearlyLoadGrowthEditField_2  matlab.ui.control.NumericEditField
        ManualDataEntryTab              matlab.ui.container.Tab
        SolarTable                      matlab.ui.control.Table
        ESSTable                        matlab.ui.control.Table
        SubstationTable                 matlab.ui.control.Table
        SolarScenarioAmountEditFieldLabel  matlab.ui.control.Label
        SolarScenarioAmountEditField    matlab.ui.control.NumericEditField
        ESSScenarioAmountEditFieldLabel  matlab.ui.control.Label
        ESSScenarioAmountEditField      matlab.ui.control.NumericEditField
        SubstationScenarioAmountEditFieldLabel  matlab.ui.control.Label
        SubstationScenarioAmountEditField  matlab.ui.control.NumericEditField
        TextArea_4                      matlab.ui.control.TextArea
        ResultsTab                      matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        OptimizationResultsTab          matlab.ui.container.Tab
        ReturnsandCarbonEmissionsTab    matlab.ui.container.Tab
        DemandGraphsTab                 matlab.ui.container.Tab
        OverloadsTab                    matlab.ui.container.Tab
        AboutthistoolTab                matlab.ui.container.Tab
    end

    
    properties (Access = private)
        
        %data
        solarData % solar data, single-column CSV
        demandData % solar data, single-column CSV
        isGraphSolar % logical value to graph solar generation data
        isGraphDemand % logical value to graph electricity demand data
        startDateGraph % index of the first data point graphed
        endDateGraph % index of the last data point graphed
        dataLength % amount of indices in data entered 
        graphYMax % maximum y value graphed, as % of largest data point entered
        graphYMin % minumum y value graphed, as % of largest data point entered
        dataMax % maximum value of all solar and demand data entered
        solarScenarioAmount % amount of scenarios for which solar data will be manually entered
        ESSScenarioAmount % amount of scenarios for which ESS data will be manually entered
        substationScenarioAmount % amount of scenarios for which substation data will be manually entered
        const = struct % constant struct that will contain almost all of the information passed into the GUI
        solarInputSize % size (in MW) of the solar array for which generation data was entered
        solarDisplaySize % factor by which to scale the solar generation data entered (for preliminary graphing)
        demandScaleFactor % factor by which to scale the demand data entered (for preliminary graphing)
        substationCapacityGraph % substation capacity entered on Input Data tab, shown as a dotted line on the graph
        battRepValue % value tied to battery replacement; one of four metrics
    end
    
    %helper functions
    methods (Access = private)
        
        function result = initializeSlider(app)
            tickspace = floor(app.dataLength/12);
            
            app.StartDatetographSlider.Limits = [1 app.dataLength];
            app.StartDatetographSlider.MajorTicks = [1 tickspace 2*tickspace 3*tickspace 4*tickspace 5*tickspace 6*tickspace 7*tickspace 8*tickspace 9*tickspace 10*tickspace 11*tickspace 12*tickspace];
            app.StartDatetographSlider.MajorTickLabels = {'Jan', 'Feb', 'Mar', 'Apr', 'May', 'Jun', 'Jul', 'Aug', 'Sep', 'Oct', 'Nov', 'Dec', ' '};
            app.StartDatetographSlider.Value = 1;
            app.startDateGraph = 1;
            
            app.EndDatetographSlider.Limits = [1 app.dataLength];
            app.EndDatetographSlider.MajorTicks = [1 tickspace 2*tickspace 3*tickspace 4*tickspace 5*tickspace 6*tickspace 7*tickspace 8*tickspace 9*tickspace 10*tickspace 11*tickspace 12*tickspace];
            app.EndDatetographSlider.MajorTickLabels = {'Jan', 'Feb', 'Mar', 'Apr', 'May', 'Jun', 'Jul', 'Aug', 'Sep', 'Oct', 'Nov', 'Dec', ' '};
            app.EndDatetographSlider.Value = app.dataLength;
            app.endDateGraph = app.dataLength;
            
            app.dataMax = max([max(app.demandData) max(app.solarData)]);
            app.MinimumSlider.Value = 1;
            app.graphYMin = 1;
            app.MaximumSlider.Value = 100;
            app.graphYMax = 100;
            
            yline(app.UIAxes,app.substationCapacityGraph,'--');
            
            result = 1;
        end
        
        function result = initializeAxes(app)
            tickspace = floor(app.dataLength/12);
            ylabel(app.UIAxes,'MWh');
            xlabel(app.UIAxes,'');
            title(app.UIAxes, 'Data Entered');
            xticks(app.UIAxes,[0 tickspace 2*tickspace 3*tickspace 4*tickspace 5*tickspace 6*tickspace 7*tickspace 8*tickspace 9*tickspace 10*tickspace 11*tickspace]);
            xticklabels(app.UIAxes,{'Jan', 'Feb', 'Mar', 'Apr', 'May', 'Jun', 'Jul', 'Aug', 'Sep', 'Oct', 'Nov', 'Dec'});
            result = 1;
        end
        
        function result = initializeSystemSpecsTab(app)
            % Set default values for system specs tab
            % default BESS algorithm is time-based
            %    make fields corresponding to time-based variables editable
            app.PeakHoursEditField.Editable = 'On';
            app.PeakHoursEndTimeEditField.Editable = 'On';
            app.OffPeakHoursEditField.Editable = 'On';
            app.OffPeakHoursEndTimeEditField.Editable = 'On';
            app.EmergencyPowerofMaximumEditField.Editable = 'On';
            app.ChargebySolarThresholdEditField.Editable = 'On';
            
            %    make fields corresponding to load-based variables uneditable
            app.ChargePercentageEditField.Editable = 'Off';
            app.DischargePercentageEditField.Editable = 'Off';
            app.DischargeFactorEditField.Editable = 'Off';
            
            %    set const variable to tell optimization that the load-based algorithm will be used
            app.const.isLoadBasedBESS = 0;
            
            %    set default time values for time-based BESS function
            app.const.startCharge = 0;
            app.OffPeakHoursEditField.Value = 0;
            
            app.const.endCharge = 7;
            app.OffPeakHoursEndTimeEditField.Value = 7;
            
            app.const.startDischarge = 17;
            app.PeakHoursEditField.Value = 17;
            
            app.const.endDischarge = 21;
            app.PeakHoursEndTimeEditField.Value = 21;
            
            %    set default values for load-based BESS function
            app.const.chargePerc = 115;
            app.ChargePercentageEditField.Value = 115;
            
            app.const.dischargePerc = 115;
            app.DischargePercentageEditField.Value = 115;
            
            app.const.dischargeFactor = 60;
            app.DischargeFactorEditField.Value = 60;

            %    set default emergency power percentage for time-based BESS
            app.const.emerBESS = 30;
            app.EmergencyPowerofMaximumEditField.Value = 30;
            
            %    set default charge by solar threshold for time-based BESS
            app.const.chargeViaSolarThreshold = 50;
            app.ChargebySolarThresholdEditField.Value = 50;
            
            %    set default hardware replacement
            app.const.yearsPerHardwRep = 10;
            app.HardwareReplacementIntervalYearsEditField.Value = 10;
            
            % set default monthly ESS charge loss
            app.const.chargeLossFactor = 0.999958904;
            app.MonthlyESSchargeLossEditField.Value = 3;
            
            % set default ESS round-trip efficiency
            app.const.roundTripEfficiency = .86;
            app.ESSRoundTripEfficiencyEditField.Value = 86;
            
            % set default ESS depth of discharge
            app.const.percDoDCap = 95;
            app.ESSDepthofDischargeEditField.Value = 95;
            
            % set default ESS Duration
            app.const.isSpecPower = 0;
            app.const.hourPowerCapBESS = 4;
            app.ESSDurationhoursEditField.Value = 4;
            
            % set default ESS Power Capacity
            app.const.chargePowerCap = 60;
            app.const.dischargePowercap = 60;
            app.ESSPowerCapacityMWEditField.Editable = 'Off';
            app.ESSPowerCapacityMWEditField.Value = 0;
            
            % Set default ESS Capacity Loss
            app.const.percBESSDeg = 2;
            app.YearlyESSCapacityLossEditField.Value = 2;
            
            % Set default solar capacity loss
            app.const.percSolarDeg = 0.7;
            app.YearlySolarCapacityLossEditField.Value = 0.7;
            
            % set default initial ESS energy
            app.const.initialEnergyBESS = 0;
            app.ESSInitialEnergyofMaximumEditField.Value = 0;
            
            % set default inverter efficiency
            app.const.inverterEfficiency = .96;
            app.InverterEfficiencyEditField.Value = 96;
            
            % set default converter efficiency
            app.const.converterEfficiency = .98;
            app.DCDCConverterEfficiencyEditField.Value = 98;
            
            % set default substation planned rating
            app.const.npCapacity = 90;
            app.SubstationPlannedRatingMWEditField.Value = 90;
            
            %   set default battery degradation variables - default is years
            app.const.percMinCapacityBattRep = 80; %user specified degredation (min SOH) as percent of total capacity to get lifetime
            app.const.yearsPerBattRep = 12; %user specificed lifetime directly in year
            app.const.isSpecLifetime = 1;
            app.BatteryReplacementEditField.Value = 12;
            app.battRepValue = 12;
            app.const.maxNumCyclesBattRep = 6000; %number of cycles before storage must be replaced
            app.const.maxEnergyThruBattRep = 2500; %maximum energy throughput of ESS, per MWh of storage
            
            result = 1;
        end
        
        function result = initializeInputDataTab(app)
            % set default values for Input Data Tab
            app.solarInputSize = 1;
            app.SizeofSolarArrayUploadedMWEditField.Value = 1;
            app.solarDisplaySize = 1;
            app.SolarArrayScaleFactorEditField.Value = 1;
            app.substationCapacityGraph = 1;
            app.SubstationCapacityMWEditField.Value = 1;
            app.demandScaleFactor = 1;
            app.DemandScaleFactorEditField.Value = 1;
            app.dataLength = 0;
            
            app.graphYMax = 100;
            app.MaximumSlider.Value = 100;
            app.graphYMin = 1;
            app.MinimumSlider.Value = 1;
            app.StartDatetographSlider.Value = 1;
            app.startDateGraph = 1;
            app.EndDatetographSlider.Value = 365;
            app.endDateGraph = 365;
            result = 1;
        end
        
        function result = initializeCostVariablesTab(app)
            % set default solar installation cost per MW USD
            app.SolarInstallationCostperMWUSDEditField.Value = 994135;
            app.const.instCostSolarPerMWUSD = 994135;
            
            % set default solar fixed installation cost USD
            app.SolarFixedInstallationCostUSDEditField.Value = 2.77e6;
            app.const.instCostSolarfixedUSD = 2.77e6;
            
            % set default solar operations and maintenance cost per MW USD
            app.SolarOperationsandMaintenanceCostperMWUSDEditField.Value = 17460;
            app.const.annualOMPerMWSolarUSD = 17460;
            
            % set default fixed solar operations and maintenance cost USD 
            app.SolarFixedOperationsandMaintenanceCostUSDEditField_2.Value = 0;
            app.const.annualOMSolarFixedUSD = 0;
            
            % set default yearly solar cost reduction
            app.SolarPercentYearlyCostReductionEditField.Value = 0.9804;
            app.const.percCostReductionSolar = 0.9804;
            
            % set default storage installation cost per MW USD
            app.const.instCostStoragePerMWUSD = 340567;
            app.ESSInstallationCostperMWhUSDEditField.Value = 340567;
            
            % set default fixed storage installation cost USD 
            app.const.instCostStorageFixedUSD = 312041;
            app.ESSFixedInstallationCostUSDEditField.Value = 312041;
            
            % set default storage operations and maintenance cost per MWh USD
            app.const.annualOMPerMWhStorageUSD = 8514.175;
            app.ESSOperationsandMaintenanceCostperMWhUSDEditField.Value = 8514.175;
            
            % set default percent yearly cost reduction
            app.const.percCostReductionBESS = 2.7848;
            app.ESSPercentYearlyCostReductionEditField.Value = 2.7848;
            
            % set default fixed storage operations and maintenance cost
            app.const.annualOMStorageFixedUSD = 7801.025;
            app.ESSFixedOperationsandMaintenanceCostUSDEditField.Value = 7801.025;
            
            % set default ESS replacement cost per MWh
            app.const.costBattRepPerMWhStorageUSD = 68113;
            app.ESSReplacementCostperMWhUSDEditField.Value = 68113;
            
            % set default fixed ESS replacement cost
            app.const.costBattRepFixedUSD = 62408;
            app.ESSFixedReplacementCostUSDEditField_2.Value = 62408;
            
            % set default interest rate (interest - inflation)
            app.const.r = 3;
            app.InterestRateEditField.Value = 3;
            
            % set default substation operations and maintenance cost per MW
            app.const.annualOMPerMWSubstUSD = 75000;
            app.SubstationOperationsandMaintenanceCostperMWUSDEditField.Value = 75000;
            
            % set default substation upgrade cost per MW USD
            app.const.instCostSubstPerMWUSD = 300000;
            app.SubstationUpgradeCostperMWUSDEditField.Value = 300000;
            
            % set default offpeak cost to generate electricity
            app.const.offPeakGenCostPerMWh = 27.5;
            app.OffPeakHoursCosttoGenerateElectricityperMWUSDEditField.Value = 27.5;
            
            % set default peak cost to generate electricity
            app.const.peakGenCostPerMWh = 44;
            app.PeakHoursCosttoGenerateElectricityperMWUSDEditField.Value = 44;
            
            % set default solar installation cost per MW CO2
            app.const.instCostSolarPerMWCO2 = 474852;
            app.SolarInstallationCarbonCostperMWtonsCO2EditField.Value = 474852;
            
            % set default storage installation cost per MW CO2
            app.const.instCostStoragePerMWCO2 = 400000;
            app.StorageInstallationCarbonCostperMWtonsCO2EditField.Value = 400000;
            
            % set default substation upgrade cost per MW CO2
            app.const.instCostSubstPerMWCO2 = 1000000;
            app.SubstationUpgradeCarbonCostperMWtonsCO2EditField.Value = 1000000;
            
            % set default solar operations and maintenance cost per MW CO2
            app.const.annualOMPerMWSolarCO2 = 43043;
            app.SolarOperationsandMaintenanceCarbonCostperMW.Value = 43043;
            
            % set default storage operations and maintenance cost per MW CO2
            app.const.annualOMPerMWhStorage = 30000;
            app.StorageOperationsandMaintenanceCarbonCostperMW.Value = 30000;
            
            % set default substation operations and maintenance cost per MW CO2
            app.const.annualOMPerMWSubst = 50000;
            app.SubstationOperationsandMaintenanceCarbonCostperMW.Value = 50000;
            
            % set default hardware replacement cost per MW CO2
            app.const.costHardwRepPerMWCO2 = 0;
            app.HardwareReplacementCarbonCostperMW.Value = 0;
            
            % set default carbon price per ton USD
            app.const.priceCarbon = 51/1016.04691;
            app.CarbonCostpertonCO2USDEditField.Value = 51;
            
            result = 1;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            result = initializeSystemSpecsTab(app); %#ok<NASGU> 
            result = initializeInputDataTab(app); %#ok<NASGU> 
            result = initializeCostVariablesTab(app); %#ok<NASGU> 
            
            % set Axes position manually
            app.UIAxes.Position = [10 190 625 210];
            
            % make all tabs scrollable to account for formatting and objects that may extend past the boundaries of tabs
            app.HomeTab.Scrollable = 'On';
            app.InputDataTab.Scrollable = 'On';
            app.SystemSpecificationsTab.Scrollable = 'On';
            app.CostVariablesTab.Scrollable = 'On';
            app.OptimizationTab.Scrollable = 'On';
            app.OptimizationVariablesTab.Scrollable = 'On';
            app.ResultsTab.Scrollable = 'On';
            
            % set default values for Optimization -> Manual Data Entry Tab
            %     set default values for solar manual data entry
            app.SolarTable.ColumnName = {'Solar Array Size (MW)'; 'Installation Cost (USD)'; ' Yearly O&M Cost (USD)'};
            app.SolarTable.RowName = {'Scenario 1'; 'Scenario 2'};
            app.SolarTable.RowName = {};
            app.SolarTable.Data = [0,0,0;0,0,0];
            ncols = size(app.SolarTable.Data,2);
            app.SolarTable.ColumnEditable = true(1,ncols);
            
            %     set default values for ESS manual data entry
            app.ESSTable.ColumnName = {'ESS Installation Size (MW)'; 'Installation Cost (USD)'; ' Yearly O&M Cost (USD)'};
            app.ESSTable.RowName = {'Scenario 1'; 'Scenario 2'};
            app.ESSTable.RowName = {};
            app.ESSTable.Data = [0,0,0;0,0,0];
            ncols = size(app.ESSTable.Data,2);
            app.ESSTable.ColumnEditable = true(1,ncols);
            
            %     set default values for substation manual data entry
            app.SubstationTable.ColumnName = {'Substation Upgrade Size (MW)'; 'Installation Cost(USD)'; 'O&M Cost (USD)'};
            app.SubstationTable.RowName = {'Scenario 1'; 'Scenario 2'};
            app.SubstationTable.RowName = {};
            app.SubstationTable.Data = [0,0,0;0,0,0];
            ncols = size(app.SubstationTable.Data,2);
            app.SubstationTable.ColumnEditable = true(1,ncols);
            
        end

        % Button pushed function: LoadSolarDataButton
        function LoadSolarDataButtonPushed(app, event)
            [solarFile,solarPath] = uigetfile('*.csv','Select a single-column CSV file of Solar Generation data');
            
            if isequal(solarFile, 0)
                return;
            end
            
            solarFile = strcat(solarPath,solarFile);
            app.solarData = readmatrix(solarFile);
            
            app.solarData(isnan(app.solarData)) = 0;
            
            if min(size(app.solarData)) > 1 || isequal(app.solarData,0)
                clear app.solarData;
                msgbox('The solar data entered was not a single-column CSV file. Please enter a single-column CSV file', 'Error', 'error');
            end
            
            if length(app.solarData) > 9000
                err = questdlg('The data entered seems to be longer than one year of hourly data. Continuing may produce unexpected results','Error','Truncate Data','Data Time Increment is not 1 Hour','Continue','Truncate Data');
                switch err
                    case 'Truncate data'
                        app.solarData = app.solarData(1:8784);
                    case 'Data Time Increment is not 1 Hour'
                        err2 = questdlg('The data will be averaged to 1 hour increments. What is the time encrement of the data entered?','Error','Minutes','Seconds','Enter New Data','Enter New Data');
                        switch err2
                            case 'Minutes'
                                newSolarData = zeros(8784,1);
                                solarTotal = 0;
                                for i = 1:length(app.solarData)
                                    solarTotal = solarTotal + app.solarData(i);
                                    if mod(i,60) == 0
                                        newSolarData(i/60) = solarTotal/60;
                                        solarTotal = 0;
                                    end
                                end
                                app.solarData = newSolarData;
                            case 'Seconds'
                                newSolarData = zeros(8784,1);
                                solarTotal = 0;
                                for i = 1:length(app.solarData)
                                    solarTotal = solarTotal + app.solarData(i);
                                    if mod(i,3600) == 0
                                        newSolarData(i/3600) = solarTotal/3600;
                                        solarTotal = 0;
                                    end
                                end
                                app.solarData = newSolarData;
                            case 'Enter New Data'
                                clear app.solarData;
                        end
                end
            end
            
            if ~isempty(app.demandData)
                if ~isequal(length(app.demandData),length(app.solarData))
                    err = questdlg('The solar data entered is not the same length as the electricity demand data entered. Would you like to truncate the longer data set or enter new data?','Error','Truncate','Enter new data','Truncate');
                    switch err
                        case 'Truncate'
                            if length(app.solarData) > length(app.demandData)
                                app.solarData = app.solarData(1:length(app.demandData));
                            else
                                app.demandData = app.demandData(1:length(app.solarData));
                            end
                        case 'Enter New Data'
                            clear app.solarData;
                    end
                end
            end
            
            app.dataLength = length(app.solarData);
            
            initializeSlider(app);
            
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            
            app.const.solarGen1MW = app.solarData ./ app.solarInputSize;
        end

        % Button pushed function: LoadDemandDataButton
        function LoadDemandDataButtonPushed(app, event)
            [demandFile,demandPath] = uigetfile('*.csv','Select a single-column CSV file of Demand data');
            
            if isequal(demandFile, 0)
                return;
            end
            
            demandFile = strcat(demandPath,demandFile);
            app.demandData = readmatrix(demandFile);
            
            app.demandData(isnan(app.demandData)) = 0;
            
            if min(size(app.demandData)) > 1 || isequal(app.demandData,0)
                clear app.demandData;
                msgbox('The input was not a single-column CSV file. Please input a single-column CSV file', 'Error', 'error');
            end
            
            if length(app.demandData) > 9000
                err = questdlg('The data entered seems to be longer than one year of hourly data. Continuing may produce unexpected results','Error','Truncate Data','Data Time Increment is not 1 Hour','Continue','Truncate Data');
                switch err
                    case 'Truncate data'
                        app.demandData = app.demandData(1:8784);
                    case 'Data Time Increment is not 1 Hour'
                        err2 = questdlg('The data will be averaged to 1 hour increments. What is the time encrement of the data entered?','Error','Minutes','Seconds','Enter New Data','Enter New Data');
                        switch err2
                            case 'Minutes'
                                newDemandData = zeros(1:8784);
                                demandTotal = 0;
                                for i = 1:length(app.demandData)
                                    demandTotal = demandTotal + app.demandData(i);
                                    if mod(i,60) == 0
                                        newDemandData(i/60) = demandTotal/60;
                                        demandTotal = 0;
                                    end
                                end
                                app.demandData = newDemandData;
                            case 'Seconds'
                                newDemandData = zeros(1:8784);
                                demandTotal = 0;
                                for i = 1:length(app.demandData)
                                    demandTotal = demandTotal + app.demandData(i);
                                    if mod(i,3600) == 0
                                        newDemandData(i/3600) = demandTotal/3600;
                                        demandTotal = 0;
                                    end
                                end
                                app.demandData = newDemandData;
                            case 'Enter New Data'
                                clear app.demandData;
                        end
                end
            end
            
            if ~isempty(app.solarData)
                if ~isequal(length(app.demandData),length(app.solarData))
                    clear app.demandData;
                    err = questdlg('The electricity demand data entered is not the same length as the solar data entered. Would you like to truncate the longer data set or enter new data?','Error','Truncate','Enter new data','Truncate');
                    switch err
                        case 'Truncate'
                            if length(app.solarData) > length(app.demandData)
                                app.solarData = app.solarData(1:length(app.demandData));
                            else
                                app.demandData = app.demandData(1:length(app.solarData));
                            end
                        case 'Enter New Data'
                            clear app.demandData;
                    end
                end
            end
            
            
            app.const.load = app.demandData;
            
            initializeSlider(app);
            
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            
            app.dataLength = length(app.demandData);
        end

        % Value changed function: SolarGenerationCheckBox
        function SolarGenerationCheckBoxValueChanged(app, event)
            app.isGraphSolar = app.SolarGenerationCheckBox.Value;
            hold(app.UIAxes,"on");
            
            if app.isGraphSolar
                initializeAxes(app);
                plot(app.UIAxes,app.solarData*app.solarDisplaySize,'-y'); 
            else
                cla(app.UIAxes);
                if app.isGraphDemand
                    plot(app.UIAxes,app.demandData, '-r');
                end
                yline(app.UIAxes,app.substationCapacityGraph,'--');
            end
        end

        % Value changed function: ElectricityDemandCheckBox
        function ElectricityDemandCheckBoxValueChanged(app, event)
            app.isGraphDemand = app.ElectricityDemandCheckBox.Value;
            hold(app.UIAxes,"on");
            
            if app.isGraphDemand
                initializeAxes(app);
                plot(app.UIAxes,app.demandData,'-r'); 
            else
                cla(app.UIAxes);
                if app.isGraphSolar
                    plot(app.UIAxes,app.solarData*app.solarDisplaySize,'-y');
                end
                yline(app.UIAxes,app.substationCapacityGraph,'--');
            end
        end

        % Value changing function: StartDatetographSlider
        function StartDatetographSliderValueChanging(app, event)
            app.startDateGraph = event.Value;
            
            if app.dataLength == 0
                msgbox('No sliders can be adjusted before data is uploaded');
                app.StartDatetographSlider.Value = 0;
                app.startDateGraph = 0;
                return;
            end
            
            if app.startDateGraph > app.endDateGraph - 10
                app.startDateGraph = app.endDateGraph - 10;
                msgbox('The start date of the data graphed cannot be the same as or later than the end date','Error','error');
            end
            
            if app.startDateGraph >= app.dataLength - 10
                app.startDateGraph = app.dataLength - 11;
                msgbox('The graph cannot show less than 10 data points');
            end
            
            xlim(app.UIAxes,[app.startDateGraph app.endDateGraph])
            
            if app.isGraphDemand
                plot(app.UIAxes,app.demandData,'-r');
            end
            if app.isGraphSolar
                plot(app.UIAxes,app.solarData*app.solarDisplaySize,'-y');
            end
        end

        % Value changing function: EndDatetographSlider
        function EndDatetographSliderValueChanging(app, event)
            app.endDateGraph = event.Value;
            
            if app.dataLength == 0
                msgbox('No sliders can be adjusted before data is uploaded');
                app.EndDatetographSlider.Value = 365;
                app.endDateGraph = 365;
                return;
            end
            
            if app.startDateGraph > app.endDateGraph - 10
                app.endDateGraph = app.startDateGraph + 10;
                msgbox('The start date of the data graphed cannot be the same as or later than the end date','Error','error');
            end
            
            if app.endDateGraph <= 10
                app.endDateGraph = 11;
                msgbox('The graph cannot show less than 10 data points');
            end
            
            xlim(app.UIAxes,[app.startDateGraph app.endDateGraph])
            
            if app.isGraphDemand
                plot(app.UIAxes,app.demandData,'-r');
            end
            if app.isGraphSolar
                plot(app.UIAxes,app.solarData*app.solarDisplaySize,'-y');
            end
        end

        % Value changing function: MinimumSlider
        function MinimumSliderValueChanging(app, event)
            app.graphYMin = event.Value;
            
            if app.dataLength == 0
                msgbox('No sliders can be adjusted before data is uploaded');
                app.MinimumSlider.Value = 0;
                app.graphYMin = 0;
                return;
            end
            
            if app.graphYMin > app.graphYMax - 5
                app.graphYMin = app.graphYMax - 5;
                msgbox('The minimum data point graphed cannot be larger than the maximum data point graphed','Error','error');
            end
            
            ylim(app.UIAxes, [(app.dataMax*(app.graphYMin/100)) (app.dataMax*(app.graphYMax/100))]);
        end

        % Value changing function: MaximumSlider
        function MaximumSliderValueChanging(app, event)
            app.graphYMax = event.Value;
            
            if app.dataLength == 0
                msgbox('No sliders can be adjusted before data is uploaded');
                app.MaximumSlider.Value = 100;
                app.graphYMax = 100;
                return;
            end
            
            if app.graphYMax < app.graphYMin + 5
                app.graphYMax = app.graphYMin + 5;
                msgbox('The minimum data point graphed cannot be larger than the maximum data point graphed','Error','error');
            end
            
            ylim(app.UIAxes, [(app.dataMax*(app.graphYMin/100)) (app.dataMax*(app.graphYMax/100))]);
        end

        % Value changed function: SolarScenarioAmountEditField
        function SolarScenarioAmountEditFieldValueChanged2(app, event)
            app.solarScenarioAmount = floor(app.SolarScenarioAmountEditField.Value);
            
            app.SolarTable.RowName = [];
            app.SolarTable.Data(:,:) = [];
            
            for i = 1:app.solarScenarioAmount
                app.SolarTable.RowName{i} = strcat('Scenario ', num2str(i));
            end
            
            nrows = app.solarScenarioAmount;
            
            app.SolarTable.Data = zeros(nrows,3);
        end

        % Value changed function: ESSScenarioAmountEditField
        function ESSScenarioAmountEditFieldValueChanged2(app, event)
            app.ESSScenarioAmount = floor(app.ESSScenarioAmountEditField.Value);
            
            app.ESSTable.RowName = [];
            app.ESSTable.Data(:,:) = [];
            
            for i = 1:app.ESSScenarioAmount
                app.ESSTable.RowName{i} = strcat('Scenario ', num2str(i));
            end
            
            nrows = size(app.ESSTable.RowName,1);
            
            app.ESSTable.Data = zeros(nrows,3);
        end

        % Value changed function: SubstationScenarioAmountEditField
        function SubstationScenarioAmountEditFieldValueChanged2(app, event)
            app.substationScenarioAmount = floor(app.SubstationScenarioAmountEditField.Value);
            
            app.SubstationTable.RowName = [];
            app.SubstationTable.Data(:,:) = [];
            
            for i = 1:app.substationScenarioAmount
                app.SubstationTable.RowName{i} = strcat('Scenario ', num2str(i));
            end
            
            nrows = size(app.SubstationTable.RowName,1);
            
            app.SubstationTable.Data = zeros(nrows,3);
        end

        % Value changed function: 
        % SizeofSolarArrayUploadedMWEditField
        function SizeofSolarArrayUploadedMWEditFieldValueChanged(app, event)
            app.solarInputSize = app.SizeofSolarArrayUploadedMWEditField.Value;
            
            if app.isGraphSolar
                cla(app.UIAxes);
                initializeAxes(app);
                plot(app.UIAxes,app.solarData*app.solarDisplaySize,'-y');
                if app.isGraphDemand
                    plot(app.UIAxes,app.demandData, '-r');
                end
                yline(app.UIAxes,app.substationCapacityGraph,'--');
            else
                cla(app.UIAxes);
                if app.isGraphDemand
                    plot(app.UIAxes,app.demandData, '-r');
                end
                yline(app.UIAxes,app.substationCapacityGraph,'--');
            end
            
            app.const.solarGen1MW = app.solarData ./ app.solarInputSize;
        end

        % Value changed function: SubstationCapacityMWEditField
        function SubstationCapacityMWEditFieldValueChanged(app, event)
            app.substationCapacityGraph = app.SubstationCapacityMWEditField.Value;
            
            cla(app.UIAxes);
            
            yline(app.UIAxes,app.substationCapacityGraph,'--');
            if app.isGraphDemand
                plot(app.UIAxes,app.demandData, '-r');
            end
            if app.isGraphSolar
                plot(app.UIAxes,app.solarData*app.solarDisplaySize,'-y');
            end
        end

        % Value changed function: SolarArrayScaleFactorEditField
        function SolarArrayScaleFactorEditFieldValueChanged(app, event)
            app.solarDisplaySize = app.SolarArrayScaleFactorEditField.Value;
            
            if app.isGraphSolar
                cla(app.UIAxes);
                initializeAxes(app);
                plot(app.UIAxes,app.solarData*app.solarDisplaySize,'-y');
                if app.isGraphDemand
                    plot(app.UIAxes,app.demandData, '-r');
                end
                yline(app.UIAxes,app.substationCapacityGraph,'--');
            else
                cla(app.UIAxes);
                if app.isGraphDemand
                    plot(app.UIAxes,app.demandData, '-r');
                end
                yline(app.UIAxes,app.substationCapacityGraph,'--');
            end
        end

        % Value changed function: DemandScaleFactorEditField
        function DemandScaleFactorEditFieldValueChanged(app, event)
            app.demandScaleFactor = app.DemandScaleFactorEditField.Value;
            
            if app.isGraphDemand
                cla(app.UIAxes);
                initializeAxes(app)
                plot(app.UIAxes,app.demandData,'-r');
                if app.isGraphSolar
                    plot(app.UIAxes,app.solarData,'-y');
                end
                yline(app.UIAxes,app.substationCapacityGraph,'--');
            else
                cla(app.UIAxes);
                if app.isGraphSolar
                    plot(app.UIAxes,app.solarData*app.solarDisplaySize,'-y');
                end
                yline(app.UIAxes,app.substationCapacityGraph,'--');
            end
        end

        % Selection changed function: ESSAlgorithmButtonGroup
        function ESSAlgorithmButtonGroupSelectionChanged(app, event)
            selectedButton = app.ESSAlgorithmButtonGroup.SelectedObject;
            if selectedButton.Text == 'Time-Based' %#ok<BDSCA> 
                % make fields corresponding to time-based variables editable
                app.PeakHoursEditField.Editable = 'On';
                app.PeakHoursEndTimeEditField.Editable = 'On';
                app.OffPeakHoursEditField.Editable = 'On';
                app.OffPeakHoursEndTimeEditField.Editable = 'On';
                app.EmergencyPowerofMaximumEditField.Editable = 'On';
                app.ChargebySolarThresholdEditField.Editable = 'On';
                
                % make fields corresponding to load-based variables uneditable
                app.ChargePercentageEditField.Editable = 'Off';
                app.DischargePercentageEditField.Editable = 'Off';
                app.DischargeFactorEditField.Editable = 'Off';
                
                % set const variable to tell optimization that the load-based algorithm will be used
                app.const.isLoadBasedBESS = 0;
                
            elseif selectedButton.Text == 'Load-Based' %#ok<BDSCA> 
                % make fields corresponding to time-based variables uneditable
                app.PeakHoursEditField.Editable = 'Off';
                app.PeakHoursEndTimeEditField.Editable = 'Off';
                app.OffPeakHoursEditField.Editable = 'Off';
                app.OffPeakHoursEndTimeEditField.Editable = 'Off';
                app.EmergencyPowerofMaximumEditField.Editable = 'Off';
                app.ChargebySolarThresholdEditField.Editable = 'Off';
                
                % make fields corresponding to load-based variables editable
                app.ChargePercentageEditField.Editable = 'On';
                app.DischargePercentageEditField.Editable = 'On';
                app.DischargeFactorEditField.Editable = 'On';
                
                % set const variable to tell optimization that the time-based algorithm will be used
                app.const.isLoadBasedBESS = 1;
           end
        end

        % Value changed function: PeakHoursEditField
        function PeakHoursEditFieldValueChanged(app, event)
            %set the value of the field to be the start discharge time, and reset the value to 0 if a non-valid value is entered
            app.const.startDischarge = floor(app.PeakHoursEditField.Value);
            app.PeakHoursEditField.Value = app.const.startDischarge;
            
            if app.const.startDischarge > 23 || app.const.startDischarge < 0
                msgbox('The hour value cannot be less than 0 or more than 23. Please enter a value between 0 and 23','Error');
                app.PeakHoursEditField.Value = 0;
                app.const.startDischarge = 0;
            end
        end

        % Value changed function: PeakHoursEndTimeEditField
        function PeakHoursEndTimeEditFieldValueChanged(app, event)
            %set the value of the field to be the end discharge time, and reset the value to 0 if a non-valid value is entered
            app.const.endDischarge = floor(app.PeakHoursEndTimeEditField.Value);
            app.PeakHoursEndTimeEditField.Value = app.const.endDischarge;
            
            if app.const.endDischarge > 23 || app.const.endDischarge < 0
                msgbox('The hour value cannot be less than 0 or more than 23. Please enter a value between 0 and 23','Error');
                app.PeakHoursEndTimeEditField.Value = 0;
                app.const.endDischarge = 0;
            end
        end

        % Value changed function: OffPeakHoursEditField
        function OffPeakHoursEditFieldValueChanged(app, event)
            %set the value of the field to be the start charge time, and reset the value to 0 if a non-valid value is entered
            app.const.startCharge = floor(app.OffPeakHoursEditField.Value);
            app.OffPeakHoursEditField.Value = app.const.startCharge;
            
            if app.const.startCharge > 23 || app.const.startCharge < 0
                msgbox('The hour value cannot be less than 0 or more than 23. Please enter a value between 0 and 23','Error');
                app.OffPeakHoursEditField.Value = 0;
                app.const.startCharge = 0;
            end
        end

        % Value changed function: OffPeakHoursEndTimeEditField
        function OffPeakHoursEndTimeEditFieldValueChanged(app, event)
            %set the value of the field to be the end charge time, and reset the value to 0 if a non-valid value is entered
            app.const.endCharge = floor(app.OffPeakHoursEndTimeEditField.Value);
            app.OffPeakHoursEndTimeEditField.Value = app.const.endCharge;
            
            if app.const.endCharge > 23 || app.const.endCharge < 0
                msgbox('The hour value cannot be less than 0 or more than 23. Please enter a value between 0 and 23','Error');
                app.OffPeakHoursEndTimeEditField.Value = 0;
                app.const.endCharge = 0;
            end
        end

        % Value changed function: EmergencyPowerofMaximumEditField
        function EmergencyPowerofMaximumEditFieldValueChanged(app, event)
            %set the value of the field to be the emergency power percentage, and reset the value to 0 if a non-valid value is entered
            app.const.emerBESS = app.EmergencyPowerofMaximumEditField.Value;
            
            if app.const.emerBESS > 100 || app.const.emerBESS < 0
                msgbox('The emergency power percentage cannot be less than 0% or greater than 100%. Please enter a value between 0 and 100');
                app.EmergencyPowerofMaximumEditField.Value = 0;
                app.const.emerBESS = 0;
            end
        end

        % Value changed function: ChargebySolarThresholdEditField
        function ChargebySolarThresholdEditFieldValueChanged(app, event)
            %set the value of the field to be the charge via solar threshold, and reset the value to 0 if a non-valid value is entered
            app.const.chargeViaSolarThreshold = app.ChargebySolarThresholdEditField.Value;
            
            if app.const.chargeViaSolarThreshold > 100 || app.const.chargeViaSolarThreshold < 0
                msgbox('The charge via solar threshold cannot less than 0% or greater than 100%. Please enter a value between 0 and 100');
                app.ChargebySolarThresholdEditField.Value = 0;
                app.const.chargeViaSolarThreshold = 0;
            end
        end

        % Value changed function: ChargePercentageEditField
        function ChargePercentageEditFieldValueChanged(app, event)
            %set the value of the field to be the charge percentage, and reset the value to 0 if a non-valid value is entered
            app.const.chargePerc = app.ChargePercentageEditField.Value;
            
            if app.const.chargePerc < 0
                msgbox('The charge percentage cannot be negative. Please enter a value greater than or equal to 0');
                app.ChargePercentageEditField.Value = 0;
                app.const.chargePerc = 0;
            end
        end

        % Selection changed function: BatteryReplacementButtonGroup
        function BatteryReplacementButtonGroupSelectionChanged(app, event)
            %set the isSpecLifetime value to the corresponding battery lifetime metric
            selectedButton = app.BatteryReplacementButtonGroup.SelectedObject;
            switch selectedButton.Text
                case "Years"
                    app.const.isSpecLifetime = 1;
                    app.const.yearsPerBattRep = app.battRepValue;
                case "Cycles"
                    app.const.isSpecLifetime = 2;
                    app.const.maxNumCyclesBattRep = app.battRepValue;
                case "Minimum SOH"
                    app.const.isSpecLifetime = 0;
                    app.const.percMinCapacityBattRep = app.battRepValue;
                case "Throughput (MWh)"
                    app.const.isSpecLifetime = 3;
                    app.const.maxEnergyThruBattRep = app.battRepValue;
            end
        end

        % Value changed function: BatteryReplacementEditField
        function BatteryReplacementEditFieldValueChanged(app, event)
            %set the value of the field to be the generic battery replacement value, then set a specific battery replacement value in the const struct
            app.battRepValue = app.BatteryReplacementEditField.Value;
            
            if app.battRepValue < 0
                msgbox('No battery replacement metric can be negative. Please enter a value greater than or equal to 0');
                app.battRepValue = 0;
                app.BatteryReplacementEditField.Value = 0;
            end
            
            switch app.const.isSpecLifetime
                case 0
                    app.const.percMinCapacityBattRep = app.battRepValue;
                case 1
                    app.const.yearsPerBattRep = app.battRepValue;
                case 2
                    app.const.maxNumCyclesBattRep = app.battRepValue;
                case 3
                    app.const.maxEnergyThuBattRep = app.battRepValue;
            end
        end

        % Value changed function: DischargePercentageEditField
        function DischargePercentageEditFieldValueChanged(app, event)
            %set the value of the field to be the charge percentage, and reset the value to 0 if a non-valid value is entered
            app.const.dischargePerc = app.DischargePercentageEditField.Value;
            
            if app.const.dischargePerc < 0
                msgbox('The discharge percentage cannot be negative. Please enter a value greater than or equal to 0');
                app.DischargePercentageEditField.Value = 0;
                app.const.dischargePerc = 0;
            end
        end

        % Value changed function: DischargeFactorEditField
        function DischargeFactorEditFieldValueChanged(app, event)
            %set the value of the field to be the charge percentage, and reset the value to 0 if a non-valid value is entered
            app.const.dischargeFactor = app.DischargeFactorEditField.Value;
            
            if  app.const.dischargeFactor < 0
                msgbox('The discharge factor cannot be negative. Please enter a value greater than or equal to 0');
                app.DischargeFactorEditField.Value = 0;
                app.const.dischargeFactor = 0;
            end
        end

        % Value changed function: 
        % HardwareReplacementIntervalYearsEditField
        function HardwareReplacementIntervalYearsEditFieldValueChanged(app, event)
           %set the value of the field to be the hardware replacementinterval, and reset the value to 0 if a non-valid value is entered
            app.const.yearsPerHardwRep = app.HardwareReplacementIntervalYearsEditField.Value;
            
            if app.const.yearsPerHardwRep < 0
                msgbox('The hardware replacement interval cannot be negative. Please enter a value greater than or equal to 0');
                app.HardwareReplacementIntervalYearsEditField.Value = 0;
                app.const.yearsPerHardwRep = 0;
            end
            
        end

        % Value changed function: MonthlyESSchargeLossEditField
        function MonthlyESSchargeLossEditFieldValueChanged(app, event)
            %set the value of the field to be the monthly ESS charge loss, and reset the value to 0 if a non-valid value is entered
            %warn the user if an unexpectedly large charge loss is entered
            chargeLoss = app.MonthlyESSchargeLossEditField.Value;
            
            if chargeLoss < 0 || chargeLoss > 100
                msgbox('The monthly charge loss cannot be less than 0% or more than 100%. Please enter a value between 0 and 100');
                chargeLoss = 0;
                app.MonthlyESSchargeLossEditField.Value = 0;
            elseif chargeLoss > 25
                msgbox('The ESS is currently set to lose more than 25% of its charge per month. Unexpected results may occur');
            end
            
            %change the monthly charge loss percentage to an hourly charge loss multiplier
            app.const.chargeLossFactor = 1 - ((chargeLoss/100)/730);           
        end

        % Value changed function: ESSRoundTripEfficiencyEditField
        function ESSRoundTripEfficiencyEditFieldValueChanged(app, event)
            roundTrip = app.ESSRoundTripEfficiencyEditField.Value;
            
            if roundTrip < 0 || roundTrip > 100
                msgbox('The ESS round trip efficiency cannot be less than 0% or more than 100%. Please enter a value between 0 and 100');
                roundTrip = 0;
                app.ESSRoundTripEfficiencyEditField.Value = 0;
            end
            
            %change the percent roundtrip efficiency to a decimal
            app.const.roundTripEfficiency = roundTrip/100;
        end

        % Value changed function: ESSDepthofDischargeEditField
        function ESSDepthofDischargeEditFieldValueChanged(app, event)
            DOD = app.ESSDepthofDischargeEditField.Value;
            
            if DOD <= 0 || DOD > 100
                msgbox('The daily depth of discharge cannot be 0% or more than 100%. Please enter a positive value less than 100');
                DOD = 0;
                app.ESSDepthofDischargeEditField.Value = 0;
            end
            
            app.const.percDoDCap = DOD;
        end

        % Value changed function: ESSDurationhoursEditField
        function ESSDurationhoursEditFieldValueChanged(app, event)
            %if the user sets the ESS duration to 0,
            ESSDuration = app.ESSDurationhoursEditField.Value;
            
            if ESSDuration < 0
                msgbox('The ESS duration cannot be less than 0 hours. Please enter 0 to specify the ESS power capacity directly or a positive value to specify the ESS duration in hours');
                ESSDuration = 0;
                app.ESSDurationhoursEditField.Value = 0;
            elseif ESSDuration > 10
                msgbox('The ESS duration is currently set abnormally high. Unexpected results may occur');
            end
            
            if ESSDuration == 0
                app.const.isSpecPower = 1;
                app.ESSPowerCapacityMWEditField.Editable = 'On';
            else
                app.const.isSpecPower = 0;
                app.const.hourPowerCapBESS = ESSDuration;
                app.ESSPowerCapacityMWEditField.Editable = 'Off';
            end
        end

        % Value changed function: ESSPowerCapacityMWEditField
        function ESSPowerCapacityMWEditFieldValueChanged(app, event)
            ESSPower = app.ESSPowerCapacityMWEditField.Value;
            
            if ESSPower <= 0
                msgbox('The ESS Power capacity cannot be 0 MW or less. Please enter a positive value');
                ESSPower = 60;
                app.ESSPowerCapacityMWEditField.Value = 60;
            end
            
            app.const.chargePowerCap = ESSPower;
            app.const.dischargePowerCap = ESSPower;
        end

        % Value changed function: YearlyESSCapacityLossEditField
        function YearlyESSCapacityLossEditFieldValueChanged(app, event)
            BESSDeg = app.YearlyESSCapacityLossEditField.Value;
            
            if BESSDeg < 0 || BESSDeg > 100
                msgbox('The Yearly ESS capacity loss cannot be less than 0% or more than 100%. Please enter a value between 0 and 100');
                app.YearlyESSCapacityLossEditField.Value = 0;
                BESSDeg = 0;
            elseif BESSDeg > 10
                msgbox('The Yearly ESS Degradation is currently set abnormally high. Unexpected results may occur');
            end
            
            app.const.percBESSDeg = BESSDeg;
        end

        % Value changed function: YearlySolarCapacityLossEditField
        function YearlySolarCapacityLossEditFieldValueChanged(app, event)
            solarDeg = app.YearlySolarCapacityLossEditField.Value;
            
            if solarDeg < 0 || solarDeg > 100
                msgbox('The Yearly Solar capacity loss cannot be less than 0% or more than 100%. Please enter a value between 0 and 100');
                app.YearlySolarCapacityLossEditField.Value = 0;
                solarDeg = 0;
            elseif solarDeg > 10
                msgbox('The Yearly Solar Degradation is currently set abnormally high. Unexpected results may occur');
            end
            
            app.const.percSolarDeg = solarDeg;
        end

        % Value changed function: ESSInitialEnergyofMaximumEditField
        function ESSInitialEnergyofMaximumEditFieldValueChanged(app, event)
            initialESS = app.ESSInitialEnergyofMaximumEditField.Value;
            
            if initialESS < 0 || initialESS > 100
                msgbox('The ESS Initial Energy cannot be less than 0% or more than 100%. Please enter a value between 0 and 100');
                app.ESSInitialEnergyofMaximumEditField.Value = 0;
                initialESS = 0;
            end
            
            app.const.initialEnergyBESS = initialESS;
        end

        % Value changed function: InverterEfficiencyEditField
        function InverterEfficiencyEditFieldValueChanged(app, event)
            inverterEff = app.InverterEfficiencyEditField.Value;
            
            if inverterEFF <= 0 || inverterEff > 100
                msgbox('The Inverter Efficiency cannot be 0% or less or more than 100%. Please enter a positive value between 0 and 100');
                app.InverterEfficiencyEditField.Value = 0;
                inverterEff = 0;
            elseif converterEFF > 70
                msgbox('The inverter efficiency is currently set abnormally low. Unexpected results may occur');
            end
            
            app.const.inverterEfficiency = inverterEff/100;
        end

        % Value changed function: DCDCConverterEfficiencyEditField
        function DCDCConverterEfficiencyEditFieldValueChanged(app, event)
            converterEff = app.DCDCConverterEfficiencyEditField.Value;
            
            if converterEFF <= 0 || converterEff > 100
                msgbox('The Converter Efficiency cannot be 0% or less or more than 100%. Please enter a positive value between 0 and 100');
                app.DCDCConverterEfficiencyEditField.Value = 0;
                converterEff = 0;
            elseif converterEFF > 70
                msgbox('The converter efficiency is currently set abnormally low. Unexpected results may occur');
            end
            
            app.const.converterEfficiency = converterEff/100;
        end

        % Value changed function: SubstationPlannedRatingMWEditField
        function SubstationPlannedRatingMWEditFieldValueChanged(app, event)
            substRating = app.SubstationPlannedRatingMWEditField.Value;
            
            if  substRating <= 0
                msgbox('The substation rating cannot be 0 MW or less. Please enter a value greater than 0');
                app.SubstationCapacityMWEditField.Value = 0.1;
                substRating = 0.1;
            end
            
            app.const.npCapacity = substRating;
        end

        % Value changed function: 
        % SolarInstallationCostperMWUSDEditField
        function SolarInstallationCostperMWUSDEditFieldValueChanged(app, event)
            solarInstCost = app.SolarInstallationCostperMWUSDEditField.Value;
            
            if  solarInstCost < 0
                msgbox('The solar installation cost cannot be negative. Please enter 0 or a value greater than 0');
                app.SolarInstallationCostperMWUSDEditField.Value = 994135;
                solarInstCost = 994135;
            end
            
            app.const.instCostSolarPerMWUSD = solarInstCost;
        end

        % Value changed function: 
        % SolarFixedInstallationCostUSDEditField
        function SolarFixedInstallationCostUSDEditFieldValueChanged(app, event)
            solarInstCost = app.SolarFixedInstallationCostUSDEditField.Value;
            
            if  solarInstCost < 0
                msgbox('The solar installation cost cannot be negative. Please enter 0 or a value greater than 0');
                app.SolarFixedInstallationCostUSDEditField.Value = 2.77e6;
                solarInstCost = 2.77e6;
            end
            
            app.const.instCostSolarFixedUSD = solarInstCost;
        end

        % Value changed function: 
        % SolarOperationsandMaintenanceCostperMWUSDEditField
        function SolarOperationsandMaintenanceCostperMWUSDEditFieldValueChanged(app, event)
            solarOM = app.SolarOperationsandMaintenanceCostperMWUSDEditField.Value;
            
            if  solarOM < 0
                msgbox('The solar operations and maintenance cost cannot be negative. Please enter 0 or a value greater than 0');
                app.SolarOperationsandMaintenanceCostperMWUSDEditField.Value = 17460;
                solarOM = 17460;
            end
            
            app.const.annualOMPerMWSolarUSD = solarOM;
        end

        % Value changed function: 
        % SolarFixedOperationsandMaintenanceCostUSDEditField_2
        function SolarFixedOperationsandMaintenanceCostUSDEditField_2ValueChanged(app, event)
            solarOM = app.SolarFixedOperationsandMaintenanceCostUSDEditField_2.Value;
            
            if  solarOM < 0
                msgbox('The solar operations and maintenance cost cannot be negative. Please enter 0 or a value greater than 0');
                app.SolarFixedOperationsandMaintenanceCostUSDEditField_2.Value = 0;
                solarOM = 0;
            end
            
            app.const.annualOMSolarFixedUSD = solarOM;
        end

        % Value changed function: 
        % SolarPercentYearlyCostReductionEditField
        function SolarPercentYearlyCostReductionEditFieldValueChanged(app, event)
            solarCostReduction = app.SolarPercentYearlyCostReductionEditField.Value;
            
            if  solarCostReduction < 0 || solarCostReduction > 100
                msgbox('The yearly solar cost reduction cannot be less than 0% or greater than 100%. Please enter a value between 0 and 100');
                app.SolarPercentYearlyCostReductionEditField.Value = 0.9804;
                solarCostReduction = 0.9804;
            elseif solarCostReduction > 30
                msgbox('The yearly percent solar cost reduction is unusually high. Unexpected results may occur');
            end
            
            app.const.percCostReductionSolar = solarCostReduction;
        end

        % Value changed function: 
        % ESSInstallationCostperMWhUSDEditField
        function ESSInstallationCostperMWhUSDEditFieldValueChanged(app, event)
            ESSInstCost = app.ESSInstallationCostperMWhUSDEditField.Value;
            
            if  ESSInstCost < 0
                msgbox('The ESS Installation cost cannot be negative. Please enter 0 or a value greater than 0');
                app.ESSInstallationCostperMWhUSDEditField.Value = 340567;
                ESSInstCost = 340567;
            end
            
            app.const.instCostStoragePerMWUSD = ESSInstCost;
        end

        % Value changed function: 
        % ESSFixedInstallationCostUSDEditField
        function ESSFixedInstallationCostUSDEditFieldValueChanged(app, event)
            ESSInstCost = app.ESSFixedInstallationCostUSDEditField.Value;
            
            if  ESSInstCost < 0
                msgbox('The installation cost cannot be negative. Please enter 0 or a value greater than 0');
                app.ESSFixedInstallationCostUSDEditField.Value = 312041;
                ESSInstCost = 312041;
            end
            
            app.const.instCostStorageFixedUSD = ESSInstCost;
        end

        % Value changed function: 
        % ESSOperationsandMaintenanceCostperMWhUSDEditField
        function ESSOperationsandMaintenanceCostperMWhUSDEditFieldValueChanged(app, event)
            ESSOMCost = app.ESSOperationsandMaintenanceCostperMWhUSDEditField.Value;
            
            if  ESSOMCost < 0
                msgbox('The solar operations and maintenance cost cannot be negative. Please enter 0 or a value greater than 0');
                app.SolarOperationsandMaintenanceCostperMWUSDEditField.Value = 8514.175;
                ESSOMCost = 8514.175;
            end
            
            app.const.annualOMperMWStorageUSD = ESSOMCost;
        end

        % Value changed function: 
        % ESSPercentYearlyCostReductionEditField
        function ESSPercentYearlyCostReductionEditFieldValueChanged(app, event)
            ESSCostReduction = app.ESSPercentYearlyCostReductionEditField.Value;
            
            if  ESSCostReduction < 0 || ESSCostReduction >= 100
                msgbox('The yearly ESS cost reduction cannot be less than 0% or greater than 100%. Please enter a value between 0 and 100');
                app.ESSPercentYearlyCostReductionEditField.Value = 2.7848;
                ESSCostReduction = 2.7848;
            elseif ESSCostReduction > 30
                msgbox('The yearly percent ESS cost reduction is unusually high. Unexpected results may occur');
            end
            
            app.const.percCostReductionBESS = ESSCostReduction;
        end

        % Value changed function: 
        % ESSFixedOperationsandMaintenanceCostUSDEditField
        function ESSFixedOperationsandMaintenanceCostUSDEditFieldValueChanged(app, event)
            ESSOM = app.ESSFixedOperationsandMaintenanceCostUSDEditField.Value;
            
            if  ESSOM < 0
                msgbox('The fixed ESS operations and maintenance cost cannot be negative. Please enter 0 or a value greater than 0');
                app.ESSFixedOperationsandMaintenanceCostUSDEditField.Value = 7801.025;
                ESSOM = 7801.025;
            end
            
            app.const.annualOMStorageFixedUSD = ESSOM;
        end

        % Value changed function: 
        % ESSReplacementCostperMWhUSDEditField
        function ESSReplacementCostperMWhUSDEditFieldValueChanged(app, event)
            ESSReplacement = app.ESSReplacementCostperMWhUSDEditField.Value;
            
            if  ESSReplacement < 0
                msgbox('The ESS replacement cost cannot be negative. Please enter 0 or a value greater than 0');
                app.ESSReplacementCostperMWhUSDEditField.Value = 68113;
                ESSReplacement = 68113;
            end
            
            app.const.costBattRepPerMWhStorageUSD = ESSReplacement;
        end

        % Value changed function: 
        % ESSFixedReplacementCostUSDEditField_2
        function ESSFixedReplacementCostUSDEditField_2ValueChanged(app, event)
            ESSReplacement = app.ESSFixedReplacementCostUSDEditField_2.Value;
            
            if  ESSReplacement < 0
                msgbox('The ESS replacement cost cannot be negative. Please enter 0 or a value greater than 0');
                app.ESSFixedReplacementCostUSDEditField_2.Value = 62408;
                ESSReplacement = 62408;
            end
            
            app.const.costBattRepFixedUSD = ESSReplacement;
        end

        % Value changed function: InterestRateEditField
        function InterestRateEditFieldValueChanged(app, event)
            interest = app.InterestRateEditField.Value;
            
            if  interest > 20
                msgbox('The interest rate seems abnormally high. Unexpected results may occur');
            elseif interest < -20
                msgbox('The interest rate seems abnormally low. Unexpected results may occur');
            end
            
            app.const.r = interest;
        end

        % Value changed function: 
        % SubstationOperationsandMaintenanceCostperMWUSDEditField
        function SubstationOperationsandMaintenanceCostperMWUSDEditFieldValueChanged(app, event)
            substationOM = app.SubstationOperationsandMaintenanceCostperMWUSDEditField.Value;
            
            if  substationOM < 0
                msgbox('The substation operations and maintenance cost cannot be negative. Please enter 0 or a value greater than 0');
                app.SubstationOperationsandMaintenanceCostperMWUSDEditField.Value = 75000;
                substationOM = 75000;
            end
            
            app.const.annualOMPerMWSubstUSD = substationOM;
        end

        % Value changed function: 
        % SubstationUpgradeCostperMWUSDEditField
        function SubstationUpgradeCostperMWUSDEditFieldValueChanged(app, event)
            substationUpgrade = app.SubstationUpgradeCostperMWUSDEditField.Value;
            
            if  substationUpgrade < 0
                msgbox('The substation upgrade cost cannot be negative. Please enter 0 or a value greater than 0');
                app.SubstationUpgradeCostperMWUSDEditField.Value = 300000;
                substationUpgrade = 300000;
            end
            
            app.const.instCostSubstPerMWUSD = substationUpgrade;
        end

        % Value changed function: 
        % OffPeakHoursCosttoGenerateElectricityperMWUSDEditField
        function OffPeakHoursCosttoGenerateElectricityperMWUSDEditFieldValueChanged(app, event)
            offpeakGenCost = app.OffPeakHoursCosttoGenerateElectricityperMWUSDEditField.Value;
            
            if  offpeakGenCost < 0
                msgbox('The electricity generation cost cannot be negative. Please enter 0 or a value greater than 0');
                app.OffPeakHoursCosttoGenerateElectricityperMWUSDEditField.Value = 27.5;
                offpeakGenCost = 27.5;
            end
            
            app.const.offPeakGenCostPerMWh = offpeakGenCost;
        end

        % Value changed function: 
        % PeakHoursCosttoGenerateElectricityperMWUSDEditField
        function PeakHoursCosttoGenerateElectricityperMWUSDEditFieldValueChanged(app, event)
            peakGenCost = app.PeakHoursCosttoGenerateElectricityperMWUSDEditField.Value;
            
            if  peakGenCost < 0
                msgbox('The electricity generation cost cannot be negative. Please enter 0 or a value greater than 0');
                app.PeakHoursCosttoGenerateElectricityperMWUSDEditField.Value = 44;
                peakGenCost = 44;
            end
            
            app.const.peakGenCostperMWh = peakGenCost;
        end

        % Value changed function: 
        % SolarInstallationCarbonCostperMWtonsCO2EditField
        function SolarInstallationCarbonCostperMWtonsCO2EditFieldValueChanged(app, event)
            solarInstCost = app.SolarInstallationCarbonCostperMWtonsCO2EditField.Value;
            
            if  solarInstCost < 0
                msgbox('The solar installation cost cannot be negative. Please enter 0 or a value greater than 0');
                app.SolarInstallationCarbonCostperMWtonsCO2EditField.Value = 474852;
                solarInstCost = 474852;
            end
            
            app.const.instCostSolarPerMWCO2 = solarInstCost;
        end

        % Value changed function: 
        % StorageInstallationCarbonCostperMWtonsCO2EditField
        function StorageInstallationCarbonCostperMWtonsCO2EditFieldValueChanged(app, event)
            ESSInstCost = app.StorageInstallationCarbonCostperMWtonsCO2EditField.Value;
            
            if  ESSInstCost < 0
                msgbox('The ESS installation cost cannot be negative. Please enter 0 or a value greater than 0');
                app.StorageInstallationCarbonCostperMWtonsCO2EditField.Value = 400000;
                ESSInstCost = 400000;
            end
            
            app.const.instCostStoragePerMWCO2 = ESSInstCost;
        end

        % Value changed function: 
        % SubstationUpgradeCarbonCostperMWtonsCO2EditField
        function SubstationUpgradeCarbonCostperMWtonsCO2EditFieldValueChanged(app, event)
            substInstCost = app.SubstationUpgradeCarbonCostperMWtonsCO2EditField.Value;
            
            if  substInstCost < 0
                msgbox('The substation installation cost cannot be negative. Please enter 0 or a value greater than 0');
                app.SubstationUpgradeCarbonCostperMWtonsCO2EditField.Value = 1000000;
                substInstCost = 1000000;
            end
            
            app.const.instCostSubstPerMWCO2 = substInstCost;
        end

        % Value changed function: 
        % SolarOperationsandMaintenanceCarbonCostperMW
        function SolarOperationsandMaintenanceCarbonCostperMWValueChanged(app, event)
            solarOM = app.SolarOperationsandMaintenanceCarbonCostperMW.Value;
            
            if  solarOM < 0
                msgbox('The solar operations and maintenance cost cannot be negative. Please enter 0 or a value greater than 0');
                app.SolarOperationsandMaintenanceCarbonCostperMW.Value = 43043;
                solarOM = 43043;
            end
            
            app.const.annualOMPerMWSolarCO2 = solarOM;
        end

        % Value changed function: 
        % StorageOperationsandMaintenanceCarbonCostperMW
        function StorageOperationsandMaintenanceCarbonCostperMWValueChanged(app, event)
            ESSOM = app.StorageOperationsandMaintenanceCarbonCostperMW.Value;
            
            if  ESSOM < 0
                msgbox('The ESS operations and maintenance cost cannot be negative. Please enter 0 or a value greater than 0');
                app.StorageOperationsandMaintenanceCarbonCostperMW.Value = 30000;
                ESSOM = 30000;
            end
            
            app.const.annualOMPerMWhStorage = ESSOM;
        end

        % Value changed function: 
        % SubstationOperationsandMaintenanceCarbonCostperMW
        function SubstationOperationsandMaintenanceCarbonCostperMWValueChanged(app, event)
            substOM = app.SubstationOperationsandMaintenanceCarbonCostperMW.Value;
            
            if  substOM < 0
                msgbox('The substation operations and maintenance cost cannot be negative. Please enter 0 or a value greater than 0');
                app.SubstationOperationsandMaintenanceCarbonCostperMW.Value = 50000;
                substOM = 50000;
            end
            
            app.const.annualOMPerMWSubst = substOM;
        end

        % Value changed function: HardwareReplacementCarbonCostperMW
        function HardwareReplacementCarbonCostperMWValueChanged(app, event)
            hardwRep = app.HardwareReplacementCarbonCostperMW.Value;
            
            if  hardwRep < 0
                msgbox('The hardware replacement cost cannot be negative. Please enter 0 or a value greater than 0');
                app.HardwareReplacementCarbonCostperMW.Value = 0;
                hardwRep = 0;
            end
            
            app.const.costHardwRepPerMWCO2 = hardwRep;
        end

        % Value changed function: CarbonCostpertonCO2USDEditField
        function CarbonCostpertonCO2USDEditFieldValueChanged(app, event)
            carbonCost = app.CarbonCostpertonCO2USDEditField.Value;
            
            if  carbonCost < 0
                msgbox('The carbon cost cannot be negative. Please enter 0 or a value greater than 0');
                app.CarbonCostpertonCO2USDEditField.Value = 51;
                carbonCost = 51;
            end
            
            app.const.priceCarbon = carbonCost/1016.04691;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.WelcomeLabel = uilabel(app.HomeTab);
            app.WelcomeLabel.Text = 'Welcome';
            app.HomesteadFLButton_2 = uibutton(app.HomeTab, 'push');
            app.HomesteadFLButton_2.Text = 'Homestead, FL';
            app.ChelanCountyWAButton_2 = uibutton(app.HomeTab, 'push');
            app.ChelanCountyWAButton_2.Text = 'Chelan County, WA';
            app.GreatPlainsWestMTButton_2 = uibutton(app.HomeTab, 'push');
            app.GreatPlainsWestMTButton_2.Text = 'Great Plains West, MT';
            app.DemoModeLabel_2 = uilabel(app.HomeTab);
            app.DemoModeLabel_2.Text = 'Demo Mode';
            app.InputDataTab = uitab(app.TabGroup);
            app.InputDataTab.Title = 'Input Data';
            app.LoadSolarDataButton = uibutton(app.InputDataTab, 'push');
            app.LoadSolarDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSolarDataButtonPushed, true);
            app.LoadSolarDataButton.Text = 'Load Solar Data';
            app.LoadDemandDataButton = uibutton(app.InputDataTab, 'push');
            app.LoadDemandDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDemandDataButtonPushed, true);
            app.LoadDemandDataButton.Text = 'Load Demand Data';
            app.LoadDataLabel = uilabel(app.InputDataTab);
            app.LoadDataLabel.Text = 'Load Data';
            app.TextArea_2 = uitextarea(app.InputDataTab);
            app.TextArea_2.Value = {'Lorem ipsum dolor sit amet, consectetur adipiscing elit. Aenean ullamcorper, lacus a ornare malesuada, enim massa efficitur augue, eu commodo nibh nisi id nisl. Nunc sed libero vel metus tempor maximus ultricies eget lorem. Cras est nisl, gravida id porta sed, cursus eget felis. Proin luctus metus et lorem blandit, sit amet congue orci rutrum. Nam gravida, odio et ultricies dignissim, risus est ultrices augue, eget eleifend ante lorem ac lectus. Suspendisse ut sagittis augue, sed pulvinar neque. Curabitur justo lorem, maximus eget pulvinar eu, tincidunt non quam. Mauris posuere lectus in sodales cursus. Curabitur blandit magna id leo maximus accumsan. Phasellus non gravida orci. Duis vehicula faucibus diam et congue.'};
            app.SolarArrayScaleFactorEditFieldLabel = uilabel(app.InputDataTab);
            app.SolarArrayScaleFactorEditFieldLabel.Text = 'Solar Array Scale Factor';
            app.SolarArrayScaleFactorEditField = uieditfield(app.InputDataTab, 'numeric');
            app.SolarArrayScaleFactorEditField.ValueChangedFcn = createCallbackFcn(app, @SolarArrayScaleFactorEditFieldValueChanged, true);
            app.SolarGenerationCheckBox = uicheckbox(app.InputDataTab);
            app.SolarGenerationCheckBox.ValueChangedFcn = createCallbackFcn(app, @SolarGenerationCheckBoxValueChanged, true);
            app.SolarGenerationCheckBox.Text = 'Solar Generation';
            app.ElectricityDemandCheckBox = uicheckbox(app.InputDataTab);
            app.ElectricityDemandCheckBox.ValueChangedFcn = createCallbackFcn(app, @ElectricityDemandCheckBoxValueChanged, true);
            app.ElectricityDemandCheckBox.Text = 'Electricity Demand';
            app.DatatoGraphLabel = uilabel(app.InputDataTab);
            app.DatatoGraphLabel.Text = 'Data to Graph';
            app.StartDatetographSliderLabel = uilabel(app.InputDataTab);
            app.StartDatetographSliderLabel.Text = {'Start Date'; '(to graph)'};
            app.StartDatetographSlider = uislider(app.InputDataTab);
            app.StartDatetographSlider.Limits = [0 365];
            app.StartDatetographSlider.ValueChangingFcn = createCallbackFcn(app, @StartDatetographSliderValueChanging, true);
            app.EndDatetographSliderLabel = uilabel(app.InputDataTab);
            app.EndDatetographSliderLabel.Text = {'End Date'; '(to graph)'};
            app.EndDatetographSlider = uislider(app.InputDataTab);
            app.EndDatetographSlider.Limits = [0 365];
            app.EndDatetographSlider.ValueChangingFcn = createCallbackFcn(app, @EndDatetographSliderValueChanging, true);
            app.MaximumSliderLabel = uilabel(app.InputDataTab);
            app.MaximumSliderLabel.Text = 'Maximum';
            app.MaximumSlider = uislider(app.InputDataTab);
            app.MaximumSlider.ValueChangingFcn = createCallbackFcn(app, @MaximumSliderValueChanging, true);
            app.MinimumSliderLabel = uilabel(app.InputDataTab);
            app.MinimumSliderLabel.Text = 'Minimum';
            app.MinimumSlider = uislider(app.InputDataTab);
            app.MinimumSlider.ValueChangingFcn = createCallbackFcn(app, @MinimumSliderValueChanging, true);
            app.SizeofSolarArrayUploadedMWEditFieldLabel = uilabel(app.InputDataTab);
            app.SizeofSolarArrayUploadedMWEditFieldLabel.Text = 'Size of Solar Array Uploaded (MW)';
            app.SizeofSolarArrayUploadedMWEditField = uieditfield(app.InputDataTab, 'numeric');
            app.SizeofSolarArrayUploadedMWEditField.ValueChangedFcn = createCallbackFcn(app, @SizeofSolarArrayUploadedMWEditFieldValueChanged, true);
            app.DemandScaleFactorEditFieldLabel = uilabel(app.InputDataTab);
            app.DemandScaleFactorEditFieldLabel.Text = 'Demand Scale Factor ';
            app.DemandScaleFactorEditField = uieditfield(app.InputDataTab, 'numeric');
            app.DemandScaleFactorEditField.ValueChangedFcn = createCallbackFcn(app, @DemandScaleFactorEditFieldValueChanged, true);
            app.SubstationCapacityMWEditFieldLabel = uilabel(app.InputDataTab);
            app.SubstationCapacityMWEditFieldLabel.Text = 'Substation Capacity (MW)';
            app.SubstationCapacityMWEditField = uieditfield(app.InputDataTab, 'numeric');
            app.SubstationCapacityMWEditField.ValueChangedFcn = createCallbackFcn(app, @SubstationCapacityMWEditFieldValueChanged, true);
            app.UIAxes = uiaxes(app.InputDataTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SystemSpecificationsTab = uitab(app.TabGroup);
            app.SystemSpecificationsTab.Title = 'System Specifications';
            app.ESSAlgorithmButtonGroup = uibuttongroup(app.SystemSpecificationsTab);
            app.ESSAlgorithmButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ESSAlgorithmButtonGroupSelectionChanged, true);
            app.ESSAlgorithmButtonGroup.Title = 'ESS Algorithm';
            app.TimeBasedButton = uitogglebutton(app.ESSAlgorithmButtonGroup);
            app.TimeBasedButton.Text = 'Time-Based';
            app.TimeBasedButton.Value = true;
            app.LoadBasedButton = uitogglebutton(app.ESSAlgorithmButtonGroup);
            app.LoadBasedButton.Text = 'Load-Based';
            app.BatteryReplacementButtonGroup = uibuttongroup(app.SystemSpecificationsTab);
            app.BatteryReplacementButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BatteryReplacementButtonGroupSelectionChanged, true);
            app.BatteryReplacementButtonGroup.Title = 'Battery Replacement';
            app.YearsButton = uitogglebutton(app.BatteryReplacementButtonGroup);
            app.YearsButton.Text = 'Years';
            app.YearsButton.Value = true;
            app.CyclesButton = uitogglebutton(app.BatteryReplacementButtonGroup);
            app.CyclesButton.Text = 'Cycles';
            app.MinimumSOHButton = uitogglebutton(app.BatteryReplacementButtonGroup);
            app.MinimumSOHButton.Text = 'Minimum SOH';
            app.ThroughputMWhButton = uitogglebutton(app.BatteryReplacementButtonGroup);
            app.ThroughputMWhButton.Text = 'Throughput (MWh)';
            app.ESSDepthofDischargeLabel = uilabel(app.SystemSpecificationsTab);
            app.ESSDepthofDischargeLabel.Text = 'ESS Depth of Discharge (%)';
            app.ESSDepthofDischargeEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.ESSDepthofDischargeEditField.ValueChangedFcn = createCallbackFcn(app, @ESSDepthofDischargeEditFieldValueChanged, true);
            app.ESSDurationhoursEditFieldLabel = uilabel(app.SystemSpecificationsTab);
            app.ESSDurationhoursEditFieldLabel.Text = 'ESS Duration (hours)';
            app.ESSDurationhoursEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.ESSDurationhoursEditField.ValueChangedFcn = createCallbackFcn(app, @ESSDurationhoursEditFieldValueChanged, true);
            app.ESSInitialEnergyofMaximumEditFieldLabel = uilabel(app.SystemSpecificationsTab);
            app.ESSInitialEnergyofMaximumEditFieldLabel.Text = 'ESS Initial Energy (% of Maximum)';
            app.ESSInitialEnergyofMaximumEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.ESSInitialEnergyofMaximumEditField.ValueChangedFcn = createCallbackFcn(app, @ESSInitialEnergyofMaximumEditFieldValueChanged, true);
            app.PeakHoursEditFieldLabel = uilabel(app.SystemSpecificationsTab);
            app.PeakHoursEditFieldLabel.Text = 'Peak Hours';
            app.PeakHoursEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.PeakHoursEditField.ValueChangedFcn = createCallbackFcn(app, @PeakHoursEditFieldValueChanged, true);
            app.OffPeakHoursEditFieldLabel = uilabel(app.SystemSpecificationsTab);
            app.OffPeakHoursEditFieldLabel.Text = 'Off-Peak Hours';
            app.OffPeakHoursEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.OffPeakHoursEditField.ValueChangedFcn = createCallbackFcn(app, @OffPeakHoursEditFieldValueChanged, true);
            app.BatteryReplacementEditFieldLabel = uilabel(app.SystemSpecificationsTab);
            app.BatteryReplacementEditFieldLabel.Text = 'Battery Replacement';
            app.BatteryReplacementEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.BatteryReplacementEditField.ValueChangedFcn = createCallbackFcn(app, @BatteryReplacementEditFieldValueChanged, true);
            app.YearlyESSCapacityLossEditFieldLabel = uilabel(app.SystemSpecificationsTab);
            app.YearlyESSCapacityLossEditFieldLabel.Text = 'Yearly ESS Capacity Loss (%)';
            app.YearlyESSCapacityLossEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.YearlyESSCapacityLossEditField.ValueChangedFcn = createCallbackFcn(app, @YearlyESSCapacityLossEditFieldValueChanged, true);
            app.YearlySolarCapacityLossEditFieldLabel = uilabel(app.SystemSpecificationsTab);
            app.YearlySolarCapacityLossEditFieldLabel.Text = 'Yearly Solar Capacity Loss (%)';
            app.YearlySolarCapacityLossEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.YearlySolarCapacityLossEditField.ValueChangedFcn = createCallbackFcn(app, @YearlySolarCapacityLossEditFieldValueChanged, true);
            app.MonthlyESSchargeLossEditFieldLabel = uilabel(app.SystemSpecificationsTab);
            app.MonthlyESSchargeLossEditFieldLabel.Text = 'Monthly ESS charge Loss (%)';
            app.MonthlyESSchargeLossEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.MonthlyESSchargeLossEditField.ValueChangedFcn = createCallbackFcn(app, @MonthlyESSchargeLossEditFieldValueChanged, true);
            app.InverterEfficiencyLabel = uilabel(app.SystemSpecificationsTab);
            app.InverterEfficiencyLabel.Text = 'Inverter Efficiency (%)';
            app.InverterEfficiencyEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.InverterEfficiencyEditField.ValueChangedFcn = createCallbackFcn(app, @InverterEfficiencyEditFieldValueChanged, true);
            app.DCDCConverterEfficiencyEditFieldLabel = uilabel(app.SystemSpecificationsTab);
            app.DCDCConverterEfficiencyEditFieldLabel.Text = 'DC-DC Converter Efficiency (%)';
            app.DCDCConverterEfficiencyEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.DCDCConverterEfficiencyEditField.ValueChangedFcn = createCallbackFcn(app, @DCDCConverterEfficiencyEditFieldValueChanged, true);
            app.ESSRoundTripEfficiencyLabel = uilabel(app.SystemSpecificationsTab);
            app.ESSRoundTripEfficiencyLabel.Text = 'ESS Round-Trip Efficiency (%)';
            app.ESSRoundTripEfficiencyEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.ESSRoundTripEfficiencyEditField.ValueChangedFcn = createCallbackFcn(app, @ESSRoundTripEfficiencyEditFieldValueChanged, true);
            app.HardwareReplacementIntervalYearsEditFieldLabel = uilabel(app.SystemSpecificationsTab);
            app.HardwareReplacementIntervalYearsEditFieldLabel.Text = 'Hardware Replacement Interval (Years)';
            app.HardwareReplacementIntervalYearsEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.HardwareReplacementIntervalYearsEditField.ValueChangedFcn = createCallbackFcn(app, @HardwareReplacementIntervalYearsEditFieldValueChanged, true);
            app.PeakHoursEndTimeEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.PeakHoursEndTimeEditField.ValueChangedFcn = createCallbackFcn(app, @PeakHoursEndTimeEditFieldValueChanged, true);
            app.OffPeakHoursEndTimeEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.OffPeakHoursEndTimeEditField.ValueChangedFcn = createCallbackFcn(app, @OffPeakHoursEndTimeEditFieldValueChanged, true);
            app.SystemSpecificationsLabel = uilabel(app.SystemSpecificationsTab);
            app.SystemSpecificationsLabel.Text = 'System Specifications';
            app.EmergencyPowerofMaximumEditFieldLabel = uilabel(app.SystemSpecificationsTab);
            app.EmergencyPowerofMaximumEditFieldLabel.Text = 'Emergency Power (% of Maximum)';
            app.EmergencyPowerofMaximumEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.EmergencyPowerofMaximumEditField.ValueChangedFcn = createCallbackFcn(app, @EmergencyPowerofMaximumEditFieldValueChanged, true);
            app.ChargebySolarThresholdLabel = uilabel(app.SystemSpecificationsTab);
            app.ChargebySolarThresholdLabel.Text = 'Charge by Solar Threshold (%)';
            app.ChargebySolarThresholdEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.ChargebySolarThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ChargebySolarThresholdEditFieldValueChanged, true);
            app.ChargePercentageLabel = uilabel(app.SystemSpecificationsTab);
            app.ChargePercentageLabel.Text = 'Charge Percentage (%)';
            app.ChargePercentageEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.ChargePercentageEditField.ValueChangedFcn = createCallbackFcn(app, @ChargePercentageEditFieldValueChanged, true);
            app.DischargePercentageLabel = uilabel(app.SystemSpecificationsTab);
            app.DischargePercentageLabel.Text = 'Discharge Percentage (%)';
            app.DischargePercentageEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.DischargePercentageEditField.ValueChangedFcn = createCallbackFcn(app, @DischargePercentageEditFieldValueChanged, true);
            app.DischargeFactorLabel = uilabel(app.SystemSpecificationsTab);
            app.DischargeFactorLabel.Text = 'Discharge Factor (%)';
            app.DischargeFactorEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.DischargeFactorEditField.ValueChangedFcn = createCallbackFcn(app, @DischargeFactorEditFieldValueChanged, true);
            app.ESSPowerCapacityMWEditFieldLabel = uilabel(app.SystemSpecificationsTab);
            app.ESSPowerCapacityMWEditFieldLabel.Text = 'ESS Power Capacity (MW)';
            app.ESSPowerCapacityMWEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.ESSPowerCapacityMWEditField.ValueChangedFcn = createCallbackFcn(app, @ESSPowerCapacityMWEditFieldValueChanged, true);
            app.SubstationPlannedRatingMWEditFieldLabel = uilabel(app.SystemSpecificationsTab);
            app.SubstationPlannedRatingMWEditFieldLabel.Text = 'Substation Planned Rating (MW)';
            app.SubstationPlannedRatingMWEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.SubstationPlannedRatingMWEditField.ValueChangedFcn = createCallbackFcn(app, @SubstationPlannedRatingMWEditFieldValueChanged, true);
            app.CostVariablesTab = uitab(app.TabGroup);
            app.CostVariablesTab.Title = 'Cost Variables';
            app.CarbonCostpertonCO2USDEditFieldLabel = uilabel(app.CostVariablesTab);
            app.CarbonCostpertonCO2USDEditFieldLabel.Text = 'Carbon Cost per ton CO2 (USD)';
            app.CarbonCostpertonCO2USDEditField = uieditfield(app.CostVariablesTab, 'numeric');
            app.CarbonCostpertonCO2USDEditField.ValueChangedFcn = createCallbackFcn(app, @CarbonCostpertonCO2USDEditFieldValueChanged, true);
            app.SolarInstallationCostperMWUSDEditFieldLabel = uilabel(app.CostVariablesTab);
            app.SolarInstallationCostperMWUSDEditFieldLabel.Text = 'Solar Installation Cost per MW (USD)';
            app.SolarInstallationCostperMWUSDEditField = uieditfield(app.CostVariablesTab, 'numeric');
            app.SolarInstallationCostperMWUSDEditField.ValueChangedFcn = createCallbackFcn(app, @SolarInstallationCostperMWUSDEditFieldValueChanged, true);
            app.SolarFixedInstallationCostUSDEditFieldLabel = uilabel(app.CostVariablesTab);
            app.SolarFixedInstallationCostUSDEditFieldLabel.Text = 'Solar Fixed Installation Cost (USD)';
            app.SolarFixedInstallationCostUSDEditField = uieditfield(app.CostVariablesTab, 'numeric');
            app.SolarFixedInstallationCostUSDEditField.ValueChangedFcn = createCallbackFcn(app, @SolarFixedInstallationCostUSDEditFieldValueChanged, true);
            app.ESSInstallationCostperMWhUSDEditFieldLabel = uilabel(app.CostVariablesTab);
            app.ESSInstallationCostperMWhUSDEditFieldLabel.Text = 'ESS Installation Cost per MWh (USD)';
            app.ESSInstallationCostperMWhUSDEditField = uieditfield(app.CostVariablesTab, 'numeric');
            app.ESSInstallationCostperMWhUSDEditField.ValueChangedFcn = createCallbackFcn(app, @ESSInstallationCostperMWhUSDEditFieldValueChanged, true);
            app.ESSFixedInstallationCostUSDEditFieldLabel = uilabel(app.CostVariablesTab);
            app.ESSFixedInstallationCostUSDEditFieldLabel.Text = 'ESS Fixed Installation Cost (USD)';
            app.ESSFixedInstallationCostUSDEditField = uieditfield(app.CostVariablesTab, 'numeric');
            app.ESSFixedInstallationCostUSDEditField.ValueChangedFcn = createCallbackFcn(app, @ESSFixedInstallationCostUSDEditFieldValueChanged, true);
            app.SolarOperationsandMaintenanceCostperMWUSDEditFieldLabel = uilabel(app.CostVariablesTab);
            app.SolarOperationsandMaintenanceCostperMWUSDEditFieldLabel.Text = 'Solar Operations and Maintenance Cost per MW (USD)';
            app.SolarOperationsandMaintenanceCostperMWUSDEditField = uieditfield(app.CostVariablesTab, 'numeric');
            app.SolarOperationsandMaintenanceCostperMWUSDEditField.ValueChangedFcn = createCallbackFcn(app, @SolarOperationsandMaintenanceCostperMWUSDEditFieldValueChanged, true);
            app.ESSOperationsandMaintenanceCostperMWhUSDEditFieldLabel = uilabel(app.CostVariablesTab);
            app.ESSOperationsandMaintenanceCostperMWhUSDEditFieldLabel.Text = 'ESS Operations and Maintenance Cost per MWh (USD)';
            app.ESSOperationsandMaintenanceCostperMWhUSDEditField = uieditfield(app.CostVariablesTab, 'numeric');
            app.ESSOperationsandMaintenanceCostperMWhUSDEditField.ValueChangedFcn = createCallbackFcn(app, @ESSOperationsandMaintenanceCostperMWhUSDEditFieldValueChanged, true);
            app.ESSFixedOperationsandMaintenanceCostUSDEditFieldLabel = uilabel(app.CostVariablesTab);
            app.ESSFixedOperationsandMaintenanceCostUSDEditFieldLabel.Text = 'ESS Fixed Operations and Maintenance Cost (USD)';
            app.ESSFixedOperationsandMaintenanceCostUSDEditField = uieditfield(app.CostVariablesTab, 'numeric');
            app.ESSFixedOperationsandMaintenanceCostUSDEditField.ValueChangedFcn = createCallbackFcn(app, @ESSFixedOperationsandMaintenanceCostUSDEditFieldValueChanged, true);
            app.SubstationUpgradeCostperMWUSDEditFieldLabel = uilabel(app.CostVariablesTab);
            app.SubstationUpgradeCostperMWUSDEditFieldLabel.Text = 'Substation Upgrade Cost per MW (USD)';
            app.SubstationUpgradeCostperMWUSDEditField = uieditfield(app.CostVariablesTab, 'numeric');
            app.SubstationUpgradeCostperMWUSDEditField.ValueChangedFcn = createCallbackFcn(app, @SubstationUpgradeCostperMWUSDEditFieldValueChanged, true);
            app.SolarPercentYearlyCostReductionLabel = uilabel(app.CostVariablesTab);
            app.SolarPercentYearlyCostReductionLabel.Text = 'Solar Percent Yearly Cost Reduction (%)';
            app.SolarPercentYearlyCostReductionEditField = uieditfield(app.CostVariablesTab, 'numeric');
            app.SolarPercentYearlyCostReductionEditField.ValueChangedFcn = createCallbackFcn(app, @SolarPercentYearlyCostReductionEditFieldValueChanged, true);
            app.ESSPercentYearlyCostReductionLabel = uilabel(app.CostVariablesTab);
            app.ESSPercentYearlyCostReductionLabel.Text = 'ESS Percent Yearly Cost Reduction (%)';
            app.ESSPercentYearlyCostReductionEditField = uieditfield(app.CostVariablesTab, 'numeric');
            app.ESSPercentYearlyCostReductionEditField.ValueChangedFcn = createCallbackFcn(app, @ESSPercentYearlyCostReductionEditFieldValueChanged, true);
            app.SolarFixedOperationsandMaintenanceCostUSDEditField_2Label = uilabel(app.CostVariablesTab);
            app.SolarFixedOperationsandMaintenanceCostUSDEditField_2Label.Text = 'Solar Fixed Operations and Maintenance Cost (USD)';
            app.SolarFixedOperationsandMaintenanceCostUSDEditField_2 = uieditfield(app.CostVariablesTab, 'numeric');
            app.SolarFixedOperationsandMaintenanceCostUSDEditField_2.ValueChangedFcn = createCallbackFcn(app, @SolarFixedOperationsandMaintenanceCostUSDEditField_2ValueChanged, true);
            app.ESSReplacementCostperMWhUSDEditFieldLabel = uilabel(app.CostVariablesTab);
            app.ESSReplacementCostperMWhUSDEditFieldLabel.Text = 'ESS Replacement Cost per MWh (USD)';
            app.ESSReplacementCostperMWhUSDEditField = uieditfield(app.CostVariablesTab, 'numeric');
            app.ESSReplacementCostperMWhUSDEditField.ValueChangedFcn = createCallbackFcn(app, @ESSReplacementCostperMWhUSDEditFieldValueChanged, true);
            app.ESSFixedReplacementCostUSDLabel = uilabel(app.CostVariablesTab);
            app.ESSFixedReplacementCostUSDLabel.Text = 'ESS Fixed ReplacementCost (USD)';
            app.ESSFixedReplacementCostUSDEditField_2 = uieditfield(app.CostVariablesTab, 'numeric');
            app.ESSFixedReplacementCostUSDEditField_2.ValueChangedFcn = createCallbackFcn(app, @ESSFixedReplacementCostUSDEditField_2ValueChanged, true);
            app.SolarInstallationCarbonCostperMWtonsCO2EditFieldLabel = uilabel(app.CostVariablesTab);
            app.SolarInstallationCarbonCostperMWtonsCO2EditFieldLabel.Text = 'Solar Installation Carbon Cost per MW (tons CO2)';
            app.SolarInstallationCarbonCostperMWtonsCO2EditField = uieditfield(app.CostVariablesTab, 'numeric');
            app.SolarInstallationCarbonCostperMWtonsCO2EditField.ValueChangedFcn = createCallbackFcn(app, @SolarInstallationCarbonCostperMWtonsCO2EditFieldValueChanged, true);
            app.StorageInstallationCarbonCostperMWtonsCO2EditFieldLabel = uilabel(app.CostVariablesTab);
            app.StorageInstallationCarbonCostperMWtonsCO2EditFieldLabel.Text = 'Storage Installation Carbon Cost per MW (tons CO2)';
            app.StorageInstallationCarbonCostperMWtonsCO2EditField = uieditfield(app.CostVariablesTab, 'numeric');
            app.StorageInstallationCarbonCostperMWtonsCO2EditField.ValueChangedFcn = createCallbackFcn(app, @StorageInstallationCarbonCostperMWtonsCO2EditFieldValueChanged, true);
            app.SubstationUpgradeCarbonCostperMWtonsCO2EditFieldLabel = uilabel(app.CostVariablesTab);
            app.SubstationUpgradeCarbonCostperMWtonsCO2EditFieldLabel.Text = 'Substation Upgrade Carbon Cost per MW (tons CO2)';
            app.SubstationUpgradeCarbonCostperMWtonsCO2EditField = uieditfield(app.CostVariablesTab, 'numeric');
            app.SubstationUpgradeCarbonCostperMWtonsCO2EditField.ValueChangedFcn = createCallbackFcn(app, @SubstationUpgradeCarbonCostperMWtonsCO2EditFieldValueChanged, true);
            app.SolarOperationsandMaintenanceCarbonCostperMWtonsCO2Label = uilabel(app.CostVariablesTab);
            app.SolarOperationsandMaintenanceCarbonCostperMWtonsCO2Label.Text = 'Solar Operations and Maintenance Carbon Cost per MW (tons CO2)';
            app.SolarOperationsandMaintenanceCarbonCostperMW = uieditfield(app.CostVariablesTab, 'numeric');
            app.SolarOperationsandMaintenanceCarbonCostperMW.ValueChangedFcn = createCallbackFcn(app, @SolarOperationsandMaintenanceCarbonCostperMWValueChanged, true);
            app.StorageOperationsandMaintenanceCarbonCostperMWtonsCO2Label = uilabel(app.CostVariablesTab);
            app.StorageOperationsandMaintenanceCarbonCostperMWtonsCO2Label.Text = 'Storage Operations and Maintenance Carbon Cost per MW (tons CO2)';
            app.StorageOperationsandMaintenanceCarbonCostperMW = uieditfield(app.CostVariablesTab, 'numeric');
            app.StorageOperationsandMaintenanceCarbonCostperMW.ValueChangedFcn = createCallbackFcn(app, @StorageOperationsandMaintenanceCarbonCostperMWValueChanged, true);
            app.SubstationOperationsandMaintenanceCarbonCostperMWtonsCO2Label = uilabel(app.CostVariablesTab);
            app.SubstationOperationsandMaintenanceCarbonCostperMWtonsCO2Label.Text = 'Substation Operations and Maintenance Carbon Cost per MW (tons CO2)';
            app.SubstationOperationsandMaintenanceCarbonCostperMW = uieditfield(app.CostVariablesTab, 'numeric');
            app.SubstationOperationsandMaintenanceCarbonCostperMW.ValueChangedFcn = createCallbackFcn(app, @SubstationOperationsandMaintenanceCarbonCostperMWValueChanged, true);
            app.HardwareReplacementCarbonCostperMWtonsCO2Label = uilabel(app.CostVariablesTab);
            app.HardwareReplacementCarbonCostperMWtonsCO2Label.Text = 'Hardware Replacement Carbon Cost per MW (tons CO2)';
            app.HardwareReplacementCarbonCostperMW = uieditfield(app.CostVariablesTab, 'numeric');
            app.HardwareReplacementCarbonCostperMW.ValueChangedFcn = createCallbackFcn(app, @HardwareReplacementCarbonCostperMWValueChanged, true);
            app.CostVariablesLabel = uilabel(app.CostVariablesTab);
            app.CostVariablesLabel.Text = 'Cost Variables';
            app.PeakHoursCosttoGenerateElectricityperMWUSDEditFieldLabel = uilabel(app.CostVariablesTab);
            app.PeakHoursCosttoGenerateElectricityperMWUSDEditFieldLabel.Text = 'Peak Hours Cost to Generate Electricity per MW (USD)';
            app.PeakHoursCosttoGenerateElectricityperMWUSDEditField = uieditfield(app.CostVariablesTab, 'numeric');
            app.PeakHoursCosttoGenerateElectricityperMWUSDEditField.ValueChangedFcn = createCallbackFcn(app, @PeakHoursCosttoGenerateElectricityperMWUSDEditFieldValueChanged, true);
            app.OffPeakHoursCosttoGenerateElectricityperMWUSDEditFieldLabel = uilabel(app.CostVariablesTab);
            app.OffPeakHoursCosttoGenerateElectricityperMWUSDEditFieldLabel.Text = 'Off-Peak Hours Cost to Generate Electricity per MW (USD)';
            app.OffPeakHoursCosttoGenerateElectricityperMWUSDEditField = uieditfield(app.CostVariablesTab, 'numeric');
            app.OffPeakHoursCosttoGenerateElectricityperMWUSDEditField.ValueChangedFcn = createCallbackFcn(app, @OffPeakHoursCosttoGenerateElectricityperMWUSDEditFieldValueChanged, true);
            app.InterestRateEditFieldLabel = uilabel(app.CostVariablesTab);
            app.InterestRateEditFieldLabel.Text = 'Interest Rate (%)';
            app.InterestRateEditField = uieditfield(app.CostVariablesTab, 'numeric');
            app.InterestRateEditField.ValueChangedFcn = createCallbackFcn(app, @InterestRateEditFieldValueChanged, true);
            app.CarbonCostsLabel = uilabel(app.CostVariablesTab);
            app.CarbonCostsLabel.Text = 'Carbon Costs';
            app.MonetaryCostsLabel = uilabel(app.CostVariablesTab);
            app.MonetaryCostsLabel.Text = 'Monetary Costs';
            app.SubstationOperationsandMaintenanceCostperMWUSDEditFieldLabel = uilabel(app.CostVariablesTab);
            app.SubstationOperationsandMaintenanceCostperMWUSDEditFieldLabel.Text = 'Substation Operations and Maintenance Cost per MW (USD)';
            app.SubstationOperationsandMaintenanceCostperMWUSDEditField = uieditfield(app.CostVariablesTab, 'numeric');
            app.SubstationOperationsandMaintenanceCostperMWUSDEditField.ValueChangedFcn = createCallbackFcn(app, @SubstationOperationsandMaintenanceCostperMWUSDEditFieldValueChanged, true);
            app.OptimizationTab = uitab(app.TabGroup);
            app.OptimizationTab.Title = 'Optimization';
            app.TabGroup2 = uitabgroup(app.OptimizationTab);
            app.OptimizationVariablesTab = uitab(app.TabGroup2);
            app.OptimizationVariablesTab.Title = 'Optimization Variables';
            app.ProjectLifetimeYearsEditFieldLabel = uilabel(app.OptimizationVariablesTab);
            app.ProjectLifetimeYearsEditFieldLabel.Text = 'Project Lifetime (Years)';
            app.ProjectLifetimeYearsEditField = uieditfield(app.OptimizationVariablesTab, 'numeric');
            app.BlackoutatSubstationRatingCheckBox = uicheckbox(app.OptimizationVariablesTab);
            app.BlackoutatSubstationRatingCheckBox.Text = 'Blackout at Substation Rating';
            app.CarbonEmissionsperMWhofEnergyProducedtonsCO2EditFieldLabel = uilabel(app.OptimizationVariablesTab);
            app.CarbonEmissionsperMWhofEnergyProducedtonsCO2EditFieldLabel.Text = 'Carbon Emissions per MWh of Energy Produced (tons CO2)';
            app.CarbonEmissionsperMWhofEnergyProducedtonsCO2EditField = uieditfield(app.OptimizationVariablesTab, 'numeric');
            app.OverloadBlackoutCostperHourUSDEditFieldLabel = uilabel(app.OptimizationVariablesTab);
            app.OverloadBlackoutCostperHourUSDEditFieldLabel.Text = 'Overload/Blackout Cost per Hour (USD)';
            app.OverloadBlackoutCostperHourUSDEditField = uieditfield(app.OptimizationVariablesTab, 'numeric');
            app.FixedOverloadBlackoutCostUSDEditFieldLabel = uilabel(app.OptimizationVariablesTab);
            app.FixedOverloadBlackoutCostUSDEditFieldLabel.Text = 'Fixed Overload/Blackout Cost (USD)';
            app.FixedOverloadBlackoutCostUSDEditField = uieditfield(app.OptimizationVariablesTab, 'numeric');
            app.SolarCapacityRangeforOptimizationMWEditFieldLabel = uilabel(app.OptimizationVariablesTab);
            app.SolarCapacityRangeforOptimizationMWEditFieldLabel.Text = 'Solar Capacity Range for Optimization (MW)';
            app.SolarCapacityRangeforOptimizationMWEditField = uieditfield(app.OptimizationVariablesTab, 'numeric');
            app.StorageCapacityRangeforOptimizationMWhEditFieldLabel = uilabel(app.OptimizationVariablesTab);
            app.StorageCapacityRangeforOptimizationMWhEditFieldLabel.Text = 'Storage Capacity Range for Optimization (MWh)';
            app.StorageCapacityRangeforOptimizationMWhEditField = uieditfield(app.OptimizationVariablesTab, 'numeric');
            app.SubstationUpgradeRangerforOptimizationMWEditFieldLabel = uilabel(app.OptimizationVariablesTab);
            app.SubstationUpgradeRangerforOptimizationMWEditFieldLabel.Text = 'Substation Upgrade Ranger for Optimization (MW)';
            app.SubstationUpgradeRangerforOptimizationMWEditField = uieditfield(app.OptimizationVariablesTab, 'numeric');
            app.TextArea_3 = uitextarea(app.OptimizationVariablesTab);
            app.TextArea_3.Value = {'Lorem ipsum dolor sit amet, consectetur adipiscing elit. Mauris aliquet sollicitudin ligula, sit amet egestas erat ultricies in. Vestibulum eleifend consequat libero sed interdum. Phasellus faucibus lectus eros, ut consequat dolor iaculis a. Donec cursus ultricies felis, non consequat ligula molestie eget. Mauris mollis in ex ac venenatis. Mauris lectus augue, iaculis at neque vitae, tristique facilisis neque. Fusce iaculis neque odio, ut sagittis nunc venenatis sit amet. Cras quis aliquet augue.'; ''; 'In hac habitasse platea dictumst. Nulla tempor, ligula sed luctus dictum, tortor lectus condimentum metus, vitae elementum nulla diam tincidunt justo. Praesent accumsan et quam ac tincidunt. Aliquam ac ultrices tortor, ac malesuada libero. Morbi risus magna, placerat sed leo sit amet, dignissim volutpat velit. Curabitur lobortis eget orci a vehicula. Nunc et sollicitudin augue. Aenean sed nulla sed mauris consequat lacinia quis in eros. Pellentesque habitant morbi tristique senectus et netus et malesuada fames ac turpis egestas. In hac habitasse platea dictumst.'; ''; 'Cras viverra libero in magna ornare, ac condimentum leo iaculis. Integer a dictum magna. Cras euismod euismod massa, quis interdum felis iaculis a. Vestibulum interdum sem nec odio fringilla, ac condimentum leo sagittis. Proin fringilla, enim ac dignissim commodo, lorem neque placerat nisl, ut blandit arcu erat vel turpis. Pellentesque id augue at neque elementum viverra eget eu augue. Suspendisse facilisis sit amet elit vitae lacinia. Pellentesque vitae magna ac neque auctor ultricies interdum sit amet odio. Donec ac velit at orci semper blandit sed id urna. Nulla vel elementum lorem, sit amet volutpat lorem. Donec quis pulvinar lacus, et placerat libero.'};
            app.OptimizationVariablesLabel = uilabel(app.OptimizationVariablesTab);
            app.OptimizationVariablesLabel.Text = 'Optimization Variables';
            app.PredictedYearlyLoadGrowthEditField_2Label = uilabel(app.OptimizationVariablesTab);
            app.PredictedYearlyLoadGrowthEditField_2Label.Text = 'Predicted Yearly Load Growth (%)';
            app.PredictedYearlyLoadGrowthEditField_2 = uieditfield(app.OptimizationVariablesTab, 'numeric');
            app.ManualDataEntryTab = uitab(app.TabGroup2);
            app.ManualDataEntryTab.Title = 'Manual Data Entry';
            app.SolarTable = uitable(app.ManualDataEntryTab);
            app.SolarTable.ColumnName = {'Column 1'; 'Column 2'; ''; ''};
            app.SolarTable.RowName = {};
            app.ESSTable = uitable(app.ManualDataEntryTab);
            app.ESSTable.ColumnName = {'Column 1'; 'Column 2'; ''; ''};
            app.ESSTable.RowName = {};
            app.SubstationTable = uitable(app.ManualDataEntryTab);
            app.SubstationTable.ColumnName = {'Column 1'; 'Column 2'; ''; ''};
            app.SubstationTable.RowName = {};
            app.SolarScenarioAmountEditFieldLabel = uilabel(app.ManualDataEntryTab);
            app.SolarScenarioAmountEditFieldLabel.Text = 'Solar Scenario Amount';
            app.SolarScenarioAmountEditField = uieditfield(app.ManualDataEntryTab, 'numeric');
            app.SolarScenarioAmountEditField.ValueChangedFcn = createCallbackFcn(app, @SolarScenarioAmountEditFieldValueChanged2, true);
            app.ESSScenarioAmountEditFieldLabel = uilabel(app.ManualDataEntryTab);
            app.ESSScenarioAmountEditFieldLabel.Text = 'ESS Scenario Amount';
            app.ESSScenarioAmountEditField = uieditfield(app.ManualDataEntryTab, 'numeric');
            app.ESSScenarioAmountEditField.ValueChangedFcn = createCallbackFcn(app, @ESSScenarioAmountEditFieldValueChanged2, true);
            app.SubstationScenarioAmountEditFieldLabel = uilabel(app.ManualDataEntryTab);
            app.SubstationScenarioAmountEditFieldLabel.Text = 'Substation Scenario Amount';
            app.SubstationScenarioAmountEditField = uieditfield(app.ManualDataEntryTab, 'numeric');
            app.SubstationScenarioAmountEditField.ValueChangedFcn = createCallbackFcn(app, @SubstationScenarioAmountEditFieldValueChanged2, true);
            app.TextArea_4 = uitextarea(app.ManualDataEntryTab);
            app.TextArea_4.Value = {'Lorem ipsum dolor sit amet, consectetur adipiscing elit. Mauris aliquet sollicitudin ligula, sit amet egestas erat ultricies in. Vestibulum eleifend consequat libero sed interdum. Phasellus faucibus lectus eros, ut consequat dolor iaculis a. Donec cursus ultricies felis, non consequat ligula molestie eget. Mauris mollis in ex ac venenatis. Mauris lectus augue, iaculis at neque vitae, tristique facilisis neque. Fusce iaculis neque odio, ut sagittis nunc venenatis sit amet. Cras quis aliquet augue.'; ''};
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.TabGroup3 = uitabgroup(app.ResultsTab);
            app.OptimizationResultsTab = uitab(app.TabGroup3);
            app.OptimizationResultsTab.Title = 'Optimization Results';
            app.ReturnsandCarbonEmissionsTab = uitab(app.TabGroup3);
            app.ReturnsandCarbonEmissionsTab.Title = 'Returns and Carbon Emissions';
            app.DemandGraphsTab = uitab(app.TabGroup3);
            app.DemandGraphsTab.Title = 'Demand Graphs';
            app.OverloadsTab = uitab(app.TabGroup3);
            app.OverloadsTab.Title = 'Overloads';
            app.AboutthistoolTab = uitab(app.TabGroup);
            app.AboutthistoolTab.Title = 'About this tool';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CurrentGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end